classdef irf_solo_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PlottingTab                     matlab.ui.container.Tab
        standardplotButton              matlab.ui.control.Button
        hrButton_4                      matlab.ui.control.Button
        hrButton_3                      matlab.ui.control.Button
        hrButton_2                      matlab.ui.control.Button
        hrButton                        matlab.ui.control.Button
        beta_n_switch                   matlab.ui.control.Switch
        BETAiCheckBox                   matlab.ui.control.CheckBox
        MAGPanel                        matlab.ui.container.Panel
        B1minCheckBox                   matlab.ui.control.CheckBox
        WaveletDropDownLabel            matlab.ui.control.Label
        FGMWaveletDropDown              matlab.ui.control.DropDown
        bfgmwavloglin                   matlab.ui.control.Switch
        SVDCheckBox                     matlab.ui.control.CheckBox
        FGMWAVCheckBox                  matlab.ui.control.CheckBox
        Switch_2                        matlab.ui.control.Switch
        BxyzCheckBox                    matlab.ui.control.CheckBox
        BCheckBox                       matlab.ui.control.CheckBox
        Switch                          matlab.ui.control.Switch
        RPWPanel                        matlab.ui.container.Panel
        SVDCheckBox_2                   matlab.ui.control.CheckBox
        bscmfiltmax                     matlab.ui.control.NumericEditField
        fmaxhzLabel_3                   matlab.ui.control.Label
        bscmfiltmin                     matlab.ui.control.NumericEditField
        fminhzLabel_2                   matlab.ui.control.Label
        edcfiltmax                      matlab.ui.control.NumericEditField
        fmaxhzLabel_2                   matlab.ui.control.Label
        edcfiltmin                      matlab.ui.control.NumericEditField
        fminhzLabel                     matlab.ui.control.Label
        SCMWaveletDropDown              matlab.ui.control.DropDown
        WaveletDropDownLabel_2          matlab.ui.control.Label
        WaveletDropDown_2Label          matlab.ui.control.Label
        EDCWaveletDropDown              matlab.ui.control.DropDown
        VRPWCheckBox                    matlab.ui.control.CheckBox
        EDCwavloglin                    matlab.ui.control.Switch
        DropDown                        matlab.ui.control.DropDown
        PoyntingCheckBox                matlab.ui.control.CheckBox
        EDCfilt_check                   matlab.ui.control.CheckBox
        EDCWAVCheckBox                  matlab.ui.control.CheckBox
        EDCCheckBox                     matlab.ui.control.CheckBox
        EDCCOORDSwitch                  matlab.ui.control.Switch
        Ne_switch                       matlab.ui.control.Switch
        nscPOTCheckBox                  matlab.ui.control.CheckBox
        bscmwavloglin                   matlab.ui.control.Switch
        scmfiltCheckBox                 matlab.ui.control.CheckBox
        BSCMFramecoodDropDown           matlab.ui.control.DropDown
        SCMwavcheck                     matlab.ui.control.CheckBox
        BSCMCheckBox                    matlab.ui.control.CheckBox
        SWAPASPanel                     matlab.ui.container.Panel
        perpparCheckBox_2               matlab.ui.control.CheckBox
        VCheckBox                       matlab.ui.control.CheckBox
        DensityCheckBox                 matlab.ui.control.CheckBox
        perpparCheckBox                 matlab.ui.control.CheckBox
        IonDEFspectrogramCheckBox       matlab.ui.control.CheckBox
        TtotCheckBox                    matlab.ui.control.CheckBox
        vpascoordSwitch                 matlab.ui.control.Switch
        VxyzCheckBox                    matlab.ui.control.CheckBox
        db_intitButton                  matlab.ui.control.Button
        SavedatatoworkspaceButton       matlab.ui.control.Button
        SELECTDATAINTERVALLabel         matlab.ui.control.Label
        iDEFLamp                        matlab.ui.control.Lamp
        iDEFLampLabel                   matlab.ui.control.Label
        TPASLamp                        matlab.ui.control.Lamp
        TPASLampLabel                   matlab.ui.control.Label
        VRPWLamp                        matlab.ui.control.Lamp
        VRPWLampLabel                   matlab.ui.control.Label
        SolORAUEditField                matlab.ui.control.NumericEditField
        SolORAUEditFieldLabel           matlab.ui.control.Label
        StatusEditFieldLabel            matlab.ui.control.Label
        StatusEditField                 matlab.ui.control.EditField
        BLampLabel                      matlab.ui.control.Label
        BLamp                           matlab.ui.control.Lamp
        BSCMLampLabel                   matlab.ui.control.Label
        BSCMLamp                        matlab.ui.control.Lamp
        nPASLamp                        matlab.ui.control.Lamp
        nPASLampLabel                   matlab.ui.control.Label
        VPASLamp                        matlab.ui.control.Lamp
        VPASLampLabel                   matlab.ui.control.Label
        EDCLamp                         matlab.ui.control.Lamp
        EDCLampLabel                    matlab.ui.control.Label
        nscpotLamp                      matlab.ui.control.Lamp
        nscpotLampLabel                 matlab.ui.control.Label
        AvailabilityLabel               matlab.ui.control.Label
        PlotdataButton                  matlab.ui.control.Button
        SelectdataproductsandoptionsLabel  matlab.ui.control.Label
        TintEditFieldLabel              matlab.ui.control.Label
        TintEditField                   matlab.ui.control.EditField
        ToDatePickerLabel               matlab.ui.control.Label
        ToDatePicker                    matlab.ui.control.DatePicker
        FromDatePickerLabel             matlab.ui.control.Label
        FromDatePicker                  matlab.ui.control.DatePicker
        SSEditField_2Label              matlab.ui.control.Label
        SSEditField_2                   matlab.ui.control.NumericEditField
        MMEditField_2Label              matlab.ui.control.Label
        MMEditField_2                   matlab.ui.control.NumericEditField
        HHEditField_2Label              matlab.ui.control.Label
        HHEditField_2                   matlab.ui.control.NumericEditField
        SSEditFieldLabel                matlab.ui.control.Label
        SSEditField                     matlab.ui.control.NumericEditField
        MMEditFieldLabel                matlab.ui.control.Label
        MMEditField                     matlab.ui.control.NumericEditField
        HHEditFieldLabel                matlab.ui.control.Label
        HHEditField                     matlab.ui.control.NumericEditField
        ImportdateButton                matlab.ui.control.Button
        dayButton                       matlab.ui.control.Button
        ccButton                        matlab.ui.control.Button
        AnalysisTab                     matlab.ui.container.Tab
        LaunchshockGUIButton            matlab.ui.control.Button
        ApproximateplasmaparametersLabel  matlab.ui.control.Label
        TeeVEditField                   matlab.ui.control.NumericEditField
        TeeVEditFieldLabel              matlab.ui.control.Label
        CalcButton                      matlab.ui.control.Button
        calc_field                      matlab.ui.control.NumericEditField
        SelectparameterDropDown         matlab.ui.control.DropDown
        SelectparameterDropDownLabel    matlab.ui.control.Label
        TieVEditField                   matlab.ui.control.NumericEditField
        TieVEditFieldLabel              matlab.ui.control.Label
        nccEditField                    matlab.ui.control.NumericEditField
        nccEditFieldLabel               matlab.ui.control.Label
        BnTEditField                    matlab.ui.control.NumericEditField
        BnTEditFieldLabel               matlab.ui.control.Label
        fmaxHzEditField                 matlab.ui.control.NumericEditField
        fmaxHzEditFieldLabel            matlab.ui.control.Label
        fminHzEditField                 matlab.ui.control.NumericEditField
        fminHzEditFieldLabel            matlab.ui.control.Label
        coordDropDown                   matlab.ui.control.DropDown
        coordDropDownLabel              matlab.ui.control.Label
        fmaxhzLabel                     matlab.ui.control.Label
        mvamaxfreq                      matlab.ui.control.NumericEditField
        fminhzEditFieldLabel            matlab.ui.control.Label
        mvaminfreq                      matlab.ui.control.NumericEditField
        AnalysisintervalEditFieldLabel  matlab.ui.control.Label
        AnalysisintervalEditField       matlab.ui.control.EditField
        pspecfilterCheckBox             matlab.ui.control.CheckBox
        PSPEC_Switch                    matlab.ui.control.Switch
        copyTintButton                  matlab.ui.control.Button
        MVAGUIButton                    matlab.ui.control.Button
        PowerspectraButton              matlab.ui.control.Button
        mvafilterCheckBox               matlab.ui.control.CheckBox
        VDF_toolTab                     matlab.ui.container.Tab
        VDFintervalEditFieldLabel       matlab.ui.control.Label
        VDFintervalEditField            matlab.ui.control.EditField
        nAvEditField                    matlab.ui.control.NumericEditField
        nAvEditFieldLabel               matlab.ui.control.Label
        VDFdateEditFieldLabel           matlab.ui.control.Label
        VDFdateEditField                matlab.ui.control.EditField
        YYYYMMDDThhmmssssZLabel         matlab.ui.control.Label
        StatusEditField_2Label          matlab.ui.control.Label
        StatusEditField_2               matlab.ui.control.EditField
        EmaxEditField                   matlab.ui.control.NumericEditField
        EmaxEditFieldLabel              matlab.ui.control.Label
        EminEditField                   matlab.ui.control.NumericEditField
        EminEditFieldLabel              matlab.ui.control.Label
        plot_vdfButton                  matlab.ui.control.Button
        RemovebackgroundvelocityCheckBox  matlab.ui.control.CheckBox
        CoordaintesDropDown             matlab.ui.control.DropDown
        CoordaintesDropDownLabel        matlab.ui.control.Label
        TimeUTCEditField                matlab.ui.control.EditField
        TimeUTCEditFieldLabel           matlab.ui.control.Label
    end


    properties (Access = private)
        DATA % Description
        vdfs
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TintEditField
        function TintEditFieldValueChanged(app, event)
            %value = app.TintEditField.Value
        end

        % Button pushed function: ImportdateButton
        function ImportdateButtonPushed(app, event)
            app.TintEditField.Value = [datestr(app.FromDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField.Value,app.MMEditField.Value,app.SSEditField.Value],'HH:MM:SS.000Z'),'/',datestr(app.ToDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField_2.Value,app.MMEditField_2.Value,app.SSEditField_2.Value],'HH:MM:SS.000Z')];
            app.AnalysisintervalEditField.Value = [datestr(app.FromDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField.Value,app.MMEditField.Value,app.SSEditField.Value],'HH:MM:SS.000Z'),'/',datestr(app.ToDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField_2.Value,app.MMEditField_2.Value,app.SSEditField_2.Value],'HH:MM:SS.000Z')];
        end

        % Button pushed function: PlotdataButton
        function PlotdataButtonPushed(app, event)

            app.DATA=[];

            if ~app.BETAiCheckBox.Value
                if (app.perpparCheckBox.Value || app.perpparCheckBox_2.Value) && ~app.DensityCheckBox.Value
                    app.DensityCheckBox.Value=1;
                    app.DATA.den_rem = 1;
                else
                    app.DATA.den_rem = 0;
                end

            end


            if app.BETAiCheckBox.Value

                if strcmp(app.beta_n_switch.Value,'nPAS')
                    app.DATA.nscPOT_rem = 0;
                    if ~app.DensityCheckBox.Value
                        app.DensityCheckBox.Value=1;
                        app.DATA.den_rem = 1;
                    else
                        app.DATA.den_rem = 0;
                    end
                else
                    if ~app.DensityCheckBox.Value
                        app.DensityCheckBox.Value=1;
                        app.DATA.den_rem = 1;
                    else
                        app.DATA.den_rem = 0;
                    end
                    if ~app.nscPOTCheckBox.Value
                        app.nscPOTCheckBox.Value=1;
                        app.DATA.nscPOT_rem = 1;
                    else
                        app.DATA.nscPOT_rem = 0;
                    end
                end

                if ~app.TtotCheckBox.Value
                    app.TtotCheckBox.Value=1;
                    app.DATA.ttot_rem = 1;
                else
                    app.DATA.ttot_rem = 0;
                end

            else
                app.DATA.nscPOT_rem = 0;
                app.DATA.ttot_rem = 0;

            end


            if app.SVDCheckBox.Value
                app.SVDCheckBox_2.Value=0;
            end
            if app.SVDCheckBox_2.Value
                app.SVDCheckBox.Value=0;
            end

            if app.SVDCheckBox_2.Value && app.SVDCheckBox.Value
                app.SVDCheckBox_2.Value=0;
                app.SVDCheckBox.Value=0;
            end

            Tint = irf.tint(app.TintEditField.Value);

            app.StatusEditField.Value = 'Loading data, please wait';
            solo_pos = solo.get_position(Tint,'predicted','frame','SOLO_SUN_RTN');
            app.SolORAUEditField.Value = norm(solo_pos.data(1,:)/(149597870700/1000));

            pause(1) % needed for text field to update, for some reason...


            app.BLamp.Color = [0.75 0.75 0.75];
            app.nscpotLamp.Color = [0.75 0.75 0.75];
            app.EDCLamp.Color = [0.75 0.75 0.75];
            app.BSCMLamp.Color = [0.75 0.75 0.75];
            app.nPASLamp.Color = [0.75 0.75 0.75];
            app.VPASLamp.Color = [0.75 0.75 0.75];
            app.VRPWLamp.Color = [0.75 0.75 0.75];
            app.TPASLamp.Color = [0.75 0.75 0.75];
            app.iDEFLamp.Color = [0.75 0.75 0.75];


            %%%%%%% density sc pot
            if app.nscPOTCheckBox.Value==1

                if strcmp(app.Ne_switch.Value,'PSP')
                    %PSP = solo.db_get_ts('solo_L3_rpw-bia-scpot', 'PSP', Tint);
                    PSP = solo.get_data('scpot',Tint);
                    if ~isempty(PSP)
                        Ne = solo.psp2ne(PSP);
                    else
                        Ne=[];
                    end
                end

                if strcmp(app.Ne_switch.Value,'L3')
                    %Ne = solo.db_get_ts('solo_L3_rpw-bia-density','DENSITY',Tint);
                    Ne = solo.get_data('nescpot',Tint);
                end


                if ~isempty(Ne)
                    app.nscpotLamp.Color = 'green';
                    Ne_avail=1;
                    app.DATA.Nescpot = Ne;
                else
                    app.nscpotLamp.Color = 'red';
                    Ne_avail=0;
                end

            else
                Ne_avail=0;
            end


            if app.DATA.nscPOT_rem
                app.nscPOTCheckBox.Value=0;
            end


            %%%%%%% BFGM DATA
            if app.BCheckBox.Value==1 || app.BxyzCheckBox.Value==1 || app.B1minCheckBox.Value==1
                %app.DATA.BFGM = solo.db_get_ts(['solo_L2_mag-',app.Switch_2.Value,'-',app.Switch.Value],['B_',upper(app.Switch_2.Value)], Tint);

                if app.B1minCheckBox.Value~=1
                    app.DATA.BFGM = solo.get_data(['L2_mag-',app.Switch_2.Value,'-',app.Switch.Value],Tint);
                else
                    app.DATA.BFGM = solo.get_data(['L2_mag-',app.Switch_2.Value,'-',app.Switch.Value,'-1-minute'],Tint);
                end

                if ~isempty(app.DATA.BFGM)
                    app.DATA.BFGM.coordinateSystem = upper(app.Switch_2.Value);
                    app.BLamp.Color = 'green';
                    BFGM_avail=1;
                    if app.FGMWAVCheckBox.Value
                        if strcmp(app.FGMWaveletDropDown.Value(1),'x') || strcmp(app.FGMWaveletDropDown.Value(1),'y') || strcmp(app.FGMWaveletDropDown.Value(1),'z')
                            app.DATA.BFGM_specrec = irf_wavelet(app.DATA.BFGM.(app.FGMWaveletDropDown.Value(1)));
                        else
                            if strcmp(app.FGMWaveletDropDown.Value(1),'abs')
                                app.DATA.BFGM_specrec = irf_wavelet(app.DATA.BFGM.(app.FGMWaveletDropDown.Value));
                            else

                                ebsp_sum = irf_ebsp([],app.DATA.BFGM,[],irf_filt(app.DATA.BFGM,0,0.05,[],3),[],[0.1 floor(0.5/double(app.DATA.BFGM.time(2)-app.DATA.BFGM.time(1)))],'fullB=dB', 'polarization', 'fac'); % polarisation based on just the magnetic field data
                                app.DATA.BFGM_specrec=struct('t',ebsp_sum.t);
                                app.DATA.BFGM_specrec.f=ebsp_sum.f;
                                app.DATA.BFGM_specrec.p=ebsp_sum.bb_xxyyzzss(:,:,4);
                                app.DATA.BFGM_specrec.f_label='f [Hz]';


                            end

                        end
                        app.DATA.BFGM_specrec.p_label={['BFGM Power ',app.FGMWaveletDropDown.Value]};

                        bfgm_wav = 1;
                    else
                        bfgm_wav = 0;
                    end


                    if app.SVDCheckBox.Value

                        b0=irf_filt(app.DATA.BFGM,0,0.05,[],3);
                        fmax2 = floor(0.5/double(app.DATA.BFGM.time(2)-app.DATA.BFGM.time(1))); % Nyqvist frequency of the data 0.5*sampling frequency
                        ebsp = irf_ebsp([],app.DATA.BFGM,[],b0,[],[0.05 fmax2],'fullB=dB', 'polarization', 'fac'); % polarisation based on just the magnetic field data
                        frequency = ebsp.f;
                        time = ebsp.t;
                        ellipticity = ebsp.ellipticity;
                        dop = ebsp.dop;
                        dopthresh = 0.7;
                        removepts = find(dop < dopthresh);
                        ellipticity(removepts) = NaN;
                        thkb = squeeze(ebsp.k_tp(:,:,1));
                        thkb(removepts) = NaN;
                        % Remove "lonely" pixels
                        msk = ellipticity;
                        msk(~isnan(msk)) = 1;
                        msk(isnan(msk)) = 0;
                        msk_denoise = bwareaopen(msk,8);
                        ellipticity(msk_denoise==0) = NaN;
                        thkb(msk_denoise==0) = NaN;

                        app.DATA.SVD.specrec_el=struct('t',time);
                        app.DATA.SVD.specrec_el.f=frequency;
                        app.DATA.SVD.specrec_el.p=ellipticity;
                        app.DATA.SVD.specrec_el.f_label='f [Hz]';
                        app.DATA.SVD.specrec_el.p_label={'Ellipticity','DOP>0.7'};

                        app.DATA.SVD.specrec_thkb=struct('t',time);
                        app.DATA.SVD.specrec_thkb.f=frequency;
                        app.DATA.SVD.specrec_thkb.p=thkb;
                        app.DATA.SVD.specrec_thkb.f_label='f [Hz]';
                        app.DATA.SVD.specrec_thkb.p_label={['thkb ' ...
                            '[deg]'],'DOP>0.7'};
                        fgm_svd_avail=1;

                    else
                        fgm_svd_avail=0;
                    end


                else
                    app.BLamp.Color = 'red';
                    BFGM_avail=0;
                    bfgm_wav = 0;
                    fgm_svd_avail=0;
                end
            else
                BFGM_avail=0;
                bfgm_wav = 0;
                fgm_svd_avail=0;
            end


            %%%%%%% BSCM DATA
            if app.BSCMCheckBox.Value
                %app.DATA.BSCM = solo.db_get_ts('solo_L2_rpw-lfr-surv-cwf-b-cdag','B_RTN', Tint);
                app.DATA.BSCM = solo.get_data('b_scm_rtn',Tint);
                if ~isempty(app.DATA.BSCM)
                    app.BSCMLamp.Color = 'green';
                    B_SCM_avail=1;
                    if strcmp(app.BSCMFramecoodDropDown.Value,'FAC')
                        if BFGM_avail
                            if strcmp(app.Switch_2.Value,'rtn')
                                B_temp = app.DATA.BFGM;
                            else
                                B_temp = solo.srf2rtn(app.DATA.BFGM);
                            end
                            app.DATA.BSCM_fac =irf_convert_fac(app.DATA.BSCM,B_temp,[1 0 0]);
                            app.DATA.BSCM_fac.name = 'dB';
                            app.DATA.BSCM_fac.coordinateSystem = 'FAC';
                        else
                            warning('FAC needs FGM data')
                            B_SCM_avail=0;
                            bscm_wav = 0;
                            scm_svd_avail=0;
                        end
                    end


                    if strcmp(app.BSCMFramecoodDropDown.Value,'RTN')
                        app.DATA.BSCM.coordinateSystem = 'RTN';
                    end
                    if strcmp(app.BSCMFramecoodDropDown.Value,'SRF')
                        app.DATA.BSCM = solo.srf2rtn(app.DATA.BSCM, -1);
                        app.DATA.BSCM.coordinateSystem = 'SRF';
                    end
                    %B_SCM_data.name = 'dB';

                    %app.DATA.BSCM = B_SCM_data;
                    if app.SCMwavcheck.Value && B_SCM_avail

                        if (strcmp(app.SCMWaveletDropDown.Value(1),'x') || strcmp(app.SCMWaveletDropDown.Value(1),'y') || strcmp(app.SCMWaveletDropDown.Value(1),'z') || strcmp(app.SCMWaveletDropDown.Value,'abs'))
                            if strcmp(app.SCMWaveletDropDown.Value(1),'x') || strcmp(app.SCMWaveletDropDown.Value(1),'y') || strcmp(app.SCMWaveletDropDown.Value(1),'z')
                                wav_comp = app.SCMWaveletDropDown.Value(1);
                            else
                                wav_comp = app.SCMWaveletDropDown.Value;
                            end

                            if strcmp(app.BSCMFramecoodDropDown.Value,'FAC')
                                warning('options not valid for FAC option, skipping wavelet')
                                bscm_wav = 0;
                            else
                                app.DATA.BSCM_specrec = irf_wavelet(app.DATA.BSCM.(wav_comp),'f',[1 0.5/(app.DATA.BSCM.time(2)-app.DATA.BSCM.time(1))]);
                                bscm_wav = 1;
                            end
                        else

                            if strcmp(app.SCMWaveletDropDown.Value,'parallel')
                                wav_comp = 'z';
                            end
                            if strcmp(app.SCMWaveletDropDown.Value,'perp1')
                                wav_comp = 'x';
                            end
                            if strcmp(app.SCMWaveletDropDown.Value,'perp2')
                                wav_comp = 'y';
                            end

                            if ~strcmp(app.BSCMFramecoodDropDown.Value,'FAC')
                                warning('Need to select FAC option, wavelet skipped be plotted')
                                bscm_wav = 0;
                            else
                                app.DATA.BSCM_specrec = irf_wavelet(app.DATA.BSCM_fac.(wav_comp),'f',[1 0.5/(app.DATA.BSCM.time(2)-app.DATA.BSCM.time(1))]);
                                bscm_wav = 1;
                            end

                        end
                        app.DATA.BSCM_specrec.p_label={['BSCM Power ',app.SCMWaveletDropDown.Value]};

                    else
                        bscm_wav = 0;
                    end
                    if app.scmfiltCheckBox.Value
                        if strcmp(app.BSCMFramecoodDropDown.Value,'FAC')
                            app.DATA.BSCM_filt = irf_filt(app.DATA.BSCM_fac,app.bscmfiltmin.Value,app.bscmfiltmax.Value,[],5);
                            app.DATA.BSCM_filt.coordinateSystem = 'FAC';
                        else
                            app.DATA.BSCM_filt = irf_filt(B_SCM_data,app.bscmfiltmin.Value,app.bscmfiltmax.Value,[],5);
                        end
                    end


                    if app.SVDCheckBox_2.Value && ~isempty(app.DATA.BSCM)

                        BDAT = app.DATA.BFGM;

                        % we need to get B data in the same co-ordiantes as
                        % the E-data.
                        if strcmp(app.DATA.BSCM.coordinateSystem,'RTN') && strcmp(BDAT.coordinateSystem,'SRF')
                            BDAT = solo.srf2rtn(BDAT); % SRF>RTN
                        end
                        if strcmp(app.DATA.BSCM.coordinateSystem,'SRF') && strcmp(BDAT.coordinateSystem,'RTN')
                            BDAT = solo.srf2rtn(BDAT,1); % RTN>SRF
                        end
                        b0=irf_filt(BDAT.resample(app.DATA.BSCM),0,0.1,[],3);

                        fmax2 = 0.5/mode(diff((app.DATA.BSCM.time.epochUnix))); % Nyqvist frequency of the data 0.5*sampling frequency
                        ebsp = irf_ebsp([],app.DATA.BSCM,[],b0,[],[0.05 fmax2],'fullB=dB', 'polarization', 'fac'); % polarisation based on just the magnetic field data
                        frequency = ebsp.f;
                        time = ebsp.t;
                        %Bsum = ebsp.bb_xxyyzzss(:,:,4);
                        ellipticity = ebsp.ellipticity;
                        dop = ebsp.dop;
                        dopthresh = 0.7;
                        removepts = find(dop < dopthresh);
                        ellipticity(removepts) = NaN;
                        thkb = squeeze(ebsp.k_tp(:,:,1));
                        thkb(removepts) = NaN;
                        % Remove "lonely" pixels
                        msk = ellipticity;
                        msk(~isnan(msk)) = 1;
                        msk(isnan(msk)) = 0;
                        msk_denoise = bwareaopen(msk,8);
                        ellipticity(msk_denoise==0) = NaN;
                        thkb(msk_denoise==0) = NaN;

                        app.DATA.SVD.specrec_el=struct('t',time);
                        app.DATA.SVD.specrec_el.f=frequency;
                        app.DATA.SVD.specrec_el.p=ellipticity;
                        app.DATA.SVD.specrec_el.f_label='f [Hz]';
                        app.DATA.SVD.specrec_el.p_label={'Ellipticity','DOP>0.7'};

                        app.DATA.SVD.specrec_thkb=struct('t',time);
                        app.DATA.SVD.specrec_thkb.f=frequency;
                        app.DATA.SVD.specrec_thkb.p=thkb;
                        app.DATA.SVD.specrec_thkb.f_label='f [Hz]';
                        app.DATA.SVD.specrec_thkb.p_label={['thkb ]' ...
                            'deg'],'DOP>0.7'};
                        scm_svd_avail=1;
                    else
                        scm_svd_avail=0;
                    end


                else
                    app.BSCMLamp.Color = 'red';
                    B_SCM_avail=0;
                    bscm_wav = 0;
                    scm_svd_avail=0;
                end
            else
                B_SCM_avail=0;
                bscm_wav = 0;
                scm_svd_avail=0;
            end


            %%%%%%% DC E field
            if app.EDCCheckBox.Value==1


                if strcmp(app.DropDown.Value,'As_is')
                    %app.DATA.EDC = solo.db_get_ts('solo_L3_rpw-bia-efield', 'EDC_SRF', Tint);
                    app.DATA.EDC = solo.get_data('e_srf',Tint);
                else
                    VDC_L2 = solo.db_get_ts('solo_L2_rpw-lfr-surv-cwf-e', 'VDC', Tint);
                    Ey = (VDC_L2.z - VDC_L2.y)*1e3/6.99; %Ey, SRF, mV/m
                    Ez = (0.5*(VDC_L2.z + VDC_L2.y)-VDC_L2.x)*1e3/11.2; %Ez, SRF, mV/m
                    app.DATA.EDC = TSeries(Ey.time,[zeros(size(Ey.data)),Ey.data+124,Ez.data-87],'TensorOrder',1,'TensorBasis','xyz','repres',{'x','y','z'});
                end

                app.DATA.EDC.data(:,1) = zeros(size(app.DATA.EDC.data(:,1))); % set first component of DC E-field as 0.

                %%% EDC is currently in SRF unless changed to RTN below
                if ~isempty(app.DATA.EDC)
                    app.EDCLamp.Color = 'green';
                    if strcmp(app.EDCCOORDSwitch.Value,'rtn')
                        app.DATA.EDC.data(:,1:2) = -app.DATA.EDC.data(:,1:2); % inverse the t component
                        app.DATA.EDC.coordinateSystem = 'RTN';
                    else
                        app.DATA.EDC.coordinateSystem = 'SRF';
                    end

                    if app.EDCfilt_check.Value
                        app.DATA.EDCfilt = irf_filt(app.DATA.EDC,app.edcfiltmin.Value,app.edcfiltmax.Value,[],5);
                        app.DATA.EDCfilt.coordinateSystem = app.DATA.EDC.coordinateSystem;
                    end
                    if app.EDCWAVCheckBox.Value

                        if strcmp(app.EDCWaveletDropDown.Value(1),'y') || strcmp(app.EDCWaveletDropDown.Value(1),'z')
                            app.DATA.EDC_specrec = irf_wavelet(app.DATA.EDC.(app.EDCWaveletDropDown.Value(1)));
                            app.DATA.EDC_specrec.p_label={['E Power ',app.EDCWaveletDropDown.Value]};
                        else
                            if strcmp(app.EDCWaveletDropDown.Value(1),'abs')
                                app.DATA.EDC_specrec = irf_wavelet(app.DATA.EDC.(app.EDCWaveletDropDown.Value));
                                app.DATA.EDC_specrec.p_label={'E Power abs'};
                            else

                                ebsp_sum = irf_ebsp(app.DATA.EDC,app.DATA.EDC,[],irf_filt(app.DATA.EDC,0,0.05,[],3),[],[0.1 floor(0.5/double(app.DATA.EDC.time(2)-app.DATA.EDC.time(1)))]);
                                app.DATA.EDC_specrec=struct('t',ebsp_sum.t);
                                app.DATA.EDC_specrec.f=ebsp_sum.f;
                                app.DATA.EDC_specrec.p=ebsp_sum.ee_xxyyzzss(:,:,4);
                                app.DATA.EDC_specrec.f_label='f [Hz]';
                                app.DATA.EDC_specrec.p_label={'E Power'};
                            end
                        end
                        edc_wav = 1;
                    else
                        edc_wav = 0;
                    end
                    EDC_avail = 1;

                    if app.PoyntingCheckBox.Value && (BFGM_avail || B_SCM_avail)

                        % we need to get B data in the same co-ordiantes as
                        % the E-data.

                        % here we check if we are have selected FGM or SCM
                        % for the B data that goes to the poynting flux
                        % calculation

                        if app.SVDCheckBox.Value
                            if ~strcmp(app.DATA.EDC.coordinateSystem,app.DATA.BFGM.coordinateSystem)
                                warning('Poynting flux and SVD in different co-ordiante systems')
                            end
                        end

                        if app.SVDCheckBox_2.Value
                            if ~strcmp(app.DATA.EDC.coordinateSystem,app.DATA.SCM.coordinateSystem)
                                warning('Poynting flux and SVD in different co-ordiante systems')
                            end
                        end

                        BDAT = app.DATA.BFGM;

                        % we need to get B data in the same co-ordiantes as
                        % the E-data.
                        if strcmp(app.DATA.EDC.coordinateSystem,'RTN') && strcmp(BDAT.coordinateSystem,'SRF')
                            BDAT = solo.srf2rtn(BDAT); % SRF>RTN
                        end
                        if strcmp(app.DATA.EDC.coordinateSystem,'SRF') && strcmp(BDAT.coordinateSystem,'RTN')
                            BDAT = solo.srf2rtn(BDAT,1); % RTN>SRF
                        end
                        fmax = 0.5/mode(diff((app.DATA.EDC.time.epochUnix))); % Nyqvist frequency of the data 0.5*sampling frequency
                        b0=irf_filt(BDAT,0,0.1,[],3);


                        % check if we want to use SCM or FGM
                        if app.SVDCheckBox_2.Value % true if we selected SCM
                            BDAT2 = app.DATA.BSCM;
                            if strcmp(app.DATA.EDC.coordinateSystem,'RTN') && strcmp(BDAT2.coordinateSystem,'SRF')
                                BDAT2 = solo.srf2rtn(BDAT2); % SRF>RTN
                            end
                            if strcmp(app.DATA.EDC.coordinateSystem,'SRF') && strcmp(BDAT.coordinateSystem,'RTN')
                                BDAT2 = solo.srf2rtn(BDAT2,1); % RTN>SRF
                            end
                        else % else we use FGM
                            BDAT2 = BDAT; % already in correct frame
                        end

                        ebsp2 =irf_ebsp(app.DATA.EDC,BDAT2,[],b0,[],[0.05 fmax],'polarization','fac','fullB=dB');%,'dEdotB=0'); % full polarisation analysis
                        pfluxz = ebsp2.pf_xyz(:,:,3)./sqrt(ebsp2.pf_xyz(:,:,1).^2+ebsp2.pf_xyz(:,:,2).^2+ebsp2.pf_xyz(:,:,3).^2);
                        frequency = ebsp2.f;
                        time = ebsp2.t;
                        Bsum = ebsp2.bb_xxyyzzss(:,:,4);
                        ellipticity = ebsp2.ellipticity;
                        dop = ebsp2.dop;
                        dopthresh = 0.7;
                        removepts = find(dop < dopthresh);
                        ellipticity(removepts) = NaN;
                        thkb = squeeze(ebsp2.k_tp(:,:,1));
                        thkb(removepts) = NaN;
                        pfluxz(removepts) = NaN;
                        % Remove "lonely" pixels
                        msk = ellipticity;
                        msk(~isnan(msk)) = 1;
                        msk(isnan(msk)) = 0;
                        msk_denoise = bwareaopen(msk,8);
                        ellipticity(msk_denoise==0) = NaN;
                        thkb(msk_denoise==0) = NaN;
                        pfluxz(msk_denoise==0) = NaN;
                        app.DATA.SVD.specrec_pflux=struct('t',time);
                        app.DATA.SVD.specrec_pflux.f=frequency;
                        app.DATA.SVD.specrec_pflux.p=pfluxz;
                        app.DATA.SVD.specrec_pflux.f_label='f [Hz]';
                        app.DATA.SVD.specrec_pflux.p_label={'S_{||}/|S| [SRF]'};

                        poynting_avail=1;
                    else
                        if app.PoyntingCheckBox.Value
                            warning('FGM or SCM data needed, Poytning flux not available')
                        end
                        poynting_avail=0;
                    end
                else
                    app.EDCLamp.Color = 'red';
                    EDC_avail=0;
                    edc_wav = 0;
                    poynting_avail=0;
                end
            else
                EDC_avail=0;
                edc_wav = 0;
                poynting_avail=0;
            end


            %%% N-PAS
            if app.DensityCheckBox.Value
                %app.DATA.npas = solo.db_get_ts('solo_L2_swa-pas-grnd-mom','N', Tint);
                app.DATA.npas = solo.get_data('ni',Tint);
                if ~isempty(app.DATA.npas)
                    app.nPASLamp.Color = 'green';
                    npas_avail=1;
                else
                    app.nPASLamp.Color = 'red';
                    npas_avail=0;
                end
            else
                npas_avail=0;
            end


            if app.DATA.den_rem
                app.DensityCheckBox.Value=0;
            end


            %%% V-PAS
            if app.VxyzCheckBox.Value || app.VCheckBox.Value
                %app.DATA.vpas =  solo.db_get_ts('solo_L2_swa-pas-grnd-mom',['V_',upper(app.vpascoordSwitch.Value)], Tint);
                app.DATA.vpas =    solo.get_data(['vi_',lower(app.vpascoordSwitch.Value)],Tint);
                if ~isempty(app.DATA.vpas)
                    app.VPASLamp.Color = 'green';
                    vpas_avail=1;

                else
                    app.VPASLamp.Color = 'red';
                    vpas_avail=0;
                end
            else
                vpas_avail=0;
            end


            %%%%%% VRPW
            if app.VRPWCheckBox.Value
                load('/Users/andrewdimmock/Dropbox/Work_doc/IRF/Projects/Active/Solar_Orbiter/solo_shocks/RPW_auto_detect/V_RPW_10min.mat')
                app.DATA.VRPW = V_RPW.tlim(Tint);
                if ~isempty(app.DATA.VRPW)
                    app.VRPWLamp.Color = 'green';
                    vrpw_avail=1;
                else
                    app.VRPWLamp.Color = 'red';
                    vrpw_avail=0;
                end
            else
                vrpw_avail=0;
            end


            %%% T-PAS
            if app.TtotCheckBox.Value || app.perpparCheckBox.Value || app.perpparCheckBox_2.Value
                %app.DATA.tpas =  solo.db_get_ts('solo_L2_swa-pas-grnd-mom','T', Tint);
                %app.DATA.P_SRF = solo.db_get_ts('solo_L2_swa-pas-grnd-mom','P_SRF', Tint);

                app.DATA.tpas = solo.get_data('ti',Tint);

                if ~isempty(app.DATA.tpas)
                    app.TPASLamp.Color = 'green';
                    tpas_avail=1;

                    if app.perpparCheckBox.Value || app.perpparCheckBox_2.Value || app.BETAiCheckBox.Value
                        %                         PXX = app.DATA.P_SRF.data(:,1);
                        %                         PYY = app.DATA.P_SRF.data(:,2);
                        %                         PZZ = app.DATA.P_SRF.data(:,3);
                        %                         PXY = app.DATA.P_SRF.data(:,4);
                        %                         PXZ = app.DATA.P_SRF.data(:,5);
                        %                         PYZ = app.DATA.P_SRF.data(:,6);
                        %                         Pten = TSeries(app.DATA.P_SRF.time,[PXX,PXY,PXZ,PYY,PYZ,PZZ]);
                        %
                        %                         BDAT_ = solo.db_get_ts(['solo_L2_mag-','srf','-','normal'],'B_SRF', Tint);
                        %                         PfacT = mms.rotate_tensor(Pten,'fac',BDAT_.resample(Pten),'pp');
                        %                         app.DATA.Pfac = TSeries(PfacT.time,[PfacT.data(:,1,1), PfacT.data(:,2,2)]);
                        %                         app.DATA.Pfac.userData = {'par','perp1','perp2'};
                        %
                        %                         %%% divide by nk to get T and then convert from kelvin
                        %                         %%% to eV
                        %                         app.DATA.Tfac = TSeries(PfacT.time,(app.DATA.Pfac.data./(app.DATA.npas.resample(app.DATA.Pfac).data.*(1.38064852*10^(-23))))./1.160451812e4);
                        %                         app.DATA.Tfac.userData = {'par','perp1','perp2'};

                        Tfac = solo.get_data('ti_fac',Tint);
                        Tfac.data(:,2:3) = [mean([Tfac.data(:,2),Tfac.data(:,3)],2), mean([Tfac.data(:,2),Tfac.data(:,3)],2)];
                        app.DATA.Tfac = TSeries(Tfac.time,Tfac.data(:,1:2));


                        beta_avail=1;


                        if app.BETAiCheckBox.Value
                            if strcmp(app.beta_n_switch.Value,'nPAS')
                                app.DATA.betai = TSeries(Pten.time,1000000*mean([PfacT.data(:,1,1), PfacT.data(:,2,2),PfacT.data(:,3,3)],2)...
                                    ./...
                                    ((BDAT_.resample(Pten).abs.data*1e-9).^2 ./ (2*1.2566e-06)));
                            else
                                app.DATA.betai = TSeries(Pten.time,(app.DATA.Nescpot.resample(Pten).data .* 1.38064852*10^(-23) .* app.DATA.tpas.data .* 1e6 .* 1.160451812e4)...
                                    ./...
                                    ((BDAT_.resample(Pten).abs.data*1e-9).^2 ./ (2*1.2566e-06)));

                            end
                        else
                            beta_avail=0;
                        end
                    else
                        beta_avail=0;

                    end


                else
                    app.TPASLamp.Color = 'red';
                    tpas_avail=0;
                    beta_avail=0;
                end

            else
                tpas_avail=0;
                beta_avail=0;
            end


            if  app.DATA.ttot_rem
                app.TtotCheckBox.Value=0;
            end


            %%% PAS ion DEF spectrogram
            if app.IonDEFspectrogramCheckBox.Value
                ieflux = solo.get_data('pas_eflux',Tint);
                if ~isempty(ieflux)
                    app.DATA.iDEF.specrec = ieflux;
                    app.iDEFLamp.Color = 'green';
                    iDEF_avail=1;
                else
                    iDEF_avail=0;
                    app.iDEFLamp.Color = 'red';
                end

            else
                iDEF_avail=0;
            end


            %%%%%% PLOTTING OPTIONS %%%%%%
            %%%%%% PLOTTING OPTIONS %%%%%%
            %%%%%% PLOTTING OPTIONS %%%%%%
            %%%%%% PLOTTING OPTIONS %%%%%%
            %%%%%% PLOTTING OPTIONS %%%%%%
            %%%%%% PLOTTING OPTIONS %%%%%%

            %%% need to check that data is requested and that it is also
            %%% available


            sel_axes = double((double([...
                app.BCheckBox.Value;...
                app.BxyzCheckBox.Value;...
                app.FGMWAVCheckBox.Value;...
                app.BSCMCheckBox.Value;...
                app.SCMwavcheck.Value;...
                (app.nscPOTCheckBox.Value || app.DensityCheckBox.Value);...
                (app.VCheckBox.Value || app.VxyzCheckBox.Value || app.VRPWCheckBox.Value);...
                app.EDCCheckBox.Value;...
                app.EDCWAVCheckBox.Value;...
                (app.TtotCheckBox.Value || app.perpparCheckBox.Value || app.perpparCheckBox_2.Value);...
                app.BETAiCheckBox.Value;...
                app.IonDEFspectrogramCheckBox.Value;...
                (app.SVDCheckBox.Value || app.SVDCheckBox_2.Value);...
                (app.SVDCheckBox.Value || app.SVDCheckBox_2.Value);...
                app.PoyntingCheckBox.Value...

                ])...
                ...
                + [...
                BFGM_avail;...
                BFGM_avail;...
                bfgm_wav;...
                B_SCM_avail;...
                bscm_wav;...
                (Ne_avail || npas_avail);...
                (vpas_avail || vrpw_avail);...
                EDC_avail;...
                edc_wav;...
                tpas_avail;...
                beta_avail;...
                iDEF_avail;...
                (fgm_svd_avail || scm_svd_avail);...
                (fgm_svd_avail || scm_svd_avail);...
                poynting_avail...
                ])==2);


            app.StatusEditField.Value = 'Plotting';
            pause(1)


            if sum(sel_axes)>0
                h = irf_figure(1,sum(sel_axes),'reset');

                if sel_axes(1)
                    hca = irf_panel('Btot');
                    irf_plot(hca,app.DATA.BFGM.abs,'linewidth',1.5);
                    ylabel(hca,'|B| [nT]');
                    set(gca,'linewidth',1.8)
                    grid(hca,'off')
                end

                if sel_axes(2)
                    hca = irf_panel('Bxyz');
                    irf_plot(hca,app.DATA.BFGM,'linewidth',1.5);
                    ylabel(hca,['Bxyz ',app.DATA.BFGM.coordinateSystem,' [nT]']);

                    if strcmp(app.DATA.BFGM.coordinateSystem,'RTN')
                        legend(hca,'r','t','n')
                    else
                        legend(hca,'X','Y','Z')
                        set(gca,'linewidth',1.8)
                    end
                    grid(hca,'off')
                end


                if sel_axes(3)
                    hca = irf_panel('B wavelet');

                    if strcmp(app.bfgmwavloglin.Value,'log')
                        irf_spectrogram(hca,app.DATA.BFGM_specrec,'log')
                        set(hca, 'YScale', 'log');
                        set(gca ,'Layer', 'Top')
                        set(gca,'TickDir','in')
                    else
                        irf_spectrogram(hca,app.DATA.BFGM_specrec,'log')
                        set(gca ,'Layer', 'Top')
                        set(gca,'TickDir','in')
                    end
                    colormap(hca,jet)
                    set(gca,'linewidth',1.8)
                    grid(hca,'off')
                end


                if sel_axes(4)
                    hca = irf_panel('BSCM');

                    if app.scmfiltCheckBox.Value
                        irf_plot(hca,app.DATA.BSCM_filt,'linewidth',1.5)
                        ylabel(hca,['delta B ',app.DATA.BSCM_filt.coordinateSystem,' [nT]']);
                    else
                        if strcmp(app.BSCMFramecoodDropDown.Value,'FAC')
                            irf_plot(hca,app.DATA.BSCM_fac,'linewidth',1.5);
                            ylabel(hca,'delta B FAC [nT]');
                            legend(hca,'perp','perp','par')
                        else
                            irf_plot(hca,app.DATA.BSCM,'linewidth',1.5);
                            ylabel(hca,['delta B ',app.DATA.BSCM.coordinateSystem,' [nT]']);

                            if strcmp(app.DATA.BSCM.coordinateSystem,'RTN')
                                legend(hca,'r','t','n')
                            else
                                legend(hca,'X','Y','Z')
                            end

                        end

                    end
grid(hca,'off')
                    set(gca,'linewidth',1.8)
                end


                if sel_axes(5)
                    hca = irf_panel('BSCM wavelet');
                    if strcmp(app.bscmwavloglin.Value,'log')
                        irf_spectrogram(hca,app.DATA.BSCM_specrec,'log')
                        set(hca, 'YScale', 'log');
                        set(gca ,'Layer', 'Top')
                        set(gca,'TickDir','in')
                    else
                        irf_spectrogram(hca,app.DATA.BSCM_specrec,'log')
                        set(gca ,'Layer', 'Top')
                        set(gca,'TickDir','in')
                    end
                    %ylim(hca,[1,max(ylim(hca))])
                    colormap(hca,jet)
                    set(gca,'linewidth',1.8)
                    grid(hca,'off')
                end


                if sel_axes(6)
                    hca = irf_panel('Ne');

                    if app.nscPOTCheckBox.Value
                        irf_plot(hca,app.DATA.Nescpot,'linewidth',1.5);
                        hold(hca)
                    end
                    if app.DensityCheckBox.Value
                        irf_plot(hca,app.DATA.npas,'linewidth',1.5)
                    end
                    ylabel(hca,'Density [cm-3]');
                    set(gca,'linewidth',1.8)
                    grid(hca,'off')
                end


                if sel_axes(7)
                    hca = irf_panel('V');

                    if app.VxyzCheckBox.Value
                        irf_plot(hca,app.DATA.vpas,'linewidth',1.5);
                        hold(hca,'on')

                        if strcmp(app.DATA.vpas.coordinateSystem,'RTN')
                            legend(hca,'r','t','n')
                        else
                            legend(hca,'X','Y','Z')
                        end


                    end
                    if app.VCheckBox.Value
                        irf_plot(hca,app.DATA.vpas.abs,'linewidth',1.5);
                        hold(hca,'on')
                        if app.VxyzCheckBox.Value

                            if strcmp(app.DATA.vpas.coordinateSystem,'RTN')
                                legend(hca,'r','t','n','|V|')
                            else
                                legend(hca,'x','y','z','|V|')
                            end

                        else
                            legend(hca,'|V|')
                        end
                    end
                    if app.VRPWCheckBox.Value
                        irf_plot(hca,app.DATA.VRPW,'linewidth',1.5)


                        if app.VxyzCheckBox.Value && app.VCheckBox.Value
                            if strcmp(app.DATA.vpas.coordinateSystem,'RTN')
                                legend(hca,'r','t','n','|V|','VRPW')
                            else
                                legend(hca,'x','y','z','|V|','VRPW')
                            end

                        end
                        if ~app.VxyzCheckBox.Value && ~app.VCheckBox.Value
                            legend(hca,'VRPW')
                        end
                        if app.VxyzCheckBox.Value && ~app.VCheckBox.Value


                            if strcmp(app.DATA.vpas.coordinateSystem,'RTN')
                                legend(hca,'r','t','n','VRPW')
                            else
                                legend(hca,'X','Y','Z','VRPW')
                            end

                        end
                        if ~app.VxyzCheckBox.Value && app.VCheckBox.Value
                            legend(hca,'|V|','VRPW')
                        end


                    end


                    if app.VxyzCheckBox.Value || app.VCheckBox.Value
                        ylabel(hca,['V ',app.DATA.vpas.coordinateSystem,' [km/s]']);
                    else
                        ylabel(hca,'VPW [km/s]');
                    end


grid(hca,'off')

                    set(gca,'linewidth',1.8)
                end


                if sel_axes(8)
                    hca = irf_panel('Exyz');

                    if app.EDCfilt_check.Value
                        irf_plot(hca,app.DATA.EDCfilt,'linewidth',1.5)
                        ylabel(hca,['E ',app.DATA.EDCfilt.coordinateSystem,' [mV/m]']);
                    else
                        irf_plot(hca,app.DATA.EDC,'linewidth',1.5);
                        ylabel(hca,['E ',app.DATA.EDC.coordinateSystem,' [mV/m]']);
                    end

                    %hca.YLim = [-9 9];

                    if strcmp(app.DATA.EDC.coordinateSystem,'RTN')
                        legend(hca,'r','t','n')
                    else
                        legend(hca,'X','Y','Z')
                    end
grid(hca,'off')
                    set(gca,'linewidth',1.8)
                end


                if sel_axes(9)
                    hca = irf_panel('EDC wavelet');
                    if strcmp(app.EDCwavloglin.Value,'log')
                        irf_spectrogram(hca,app.DATA.EDC_specrec,'log')
                        set(hca, 'YScale', 'log');
                        set(gca ,'Layer', 'Top')
                        set(gca,'TickDir','in')
                    else
                        irf_spectrogram(hca,app.DATA.EDC_specrec,'log')
                        set(gca ,'Layer', 'Top')
                        set(gca,'TickDir','in')
                    end
                    colormap(hca,jet)
                    set(gca,'linewidth',1.8)
                    grid(hca,'off')
                end


                if sel_axes(10)
                    hca = irf_panel('TPAS');

                    if app.TtotCheckBox.Value
                        irf_plot(hca,app.DATA.tpas,'linewidth',1.5);
                        hold(hca,'on')
                    end

                    if app.perpparCheckBox.Value
                        irf_plot(hca,app.DATA.Tfac,'linewidth',1.5);
                        hold(hca,'on')
                    end


                    if app.perpparCheckBox_2.Value
                        irf_plot(hca,TSeries(app.DATA.Tfac.time,app.DATA.Tfac.data(:,2)./app.DATA.Tfac.data(:,1)),'linewidth',1.5);
                        hold(hca,'on')
                    end


                    if app.TtotCheckBox.Value && app.perpparCheckBox.Value && app.perpparCheckBox_2.Value
                        legend(hca,'Ttot','par','perp','perp/par')
                    end
                    if app.TtotCheckBox.Value && ~app.perpparCheckBox.Value && app.perpparCheckBox_2.Value
                        legend(hca,'Ttot','perp/par')
                    end
                    if ~app.TtotCheckBox.Value && app.perpparCheckBox.Value && app.perpparCheckBox_2.Value
                        legend(hca,'par','perp','perp/par')
                    end
                    if ~app.TtotCheckBox.Value && ~app.perpparCheckBox.Value && app.perpparCheckBox_2.Value
                        legend(hca,'perp/par')
                    end


                    if app.TtotCheckBox.Value && app.perpparCheckBox.Value && ~app.perpparCheckBox_2.Value
                        legend(hca,'Ttot','par','perp')
                    end
                    if app.TtotCheckBox.Value && ~app.perpparCheckBox.Value && ~app.perpparCheckBox_2.Value
                        legend(hca,'Ttot')
                    end
                    if ~app.TtotCheckBox.Value && app.perpparCheckBox.Value && ~app.perpparCheckBox_2.Value
                        legend(hca,'par','perp')
                    end

                    set(gca,'linewidth',1.8)
grid(hca,'off')
                    ylabel(hca,'Temp [eV]');
                end


                if sel_axes(11)
                    hca = irf_panel('betai');
                    irf_plot(hca,app.DATA.betai,'linewidth',1.5)
                    ylabel(hca,['ion beta - ',app.beta_n_switch.Value]);
                    set(gca,'linewidth',1.8)
                    grid(hca,'off')
                end


                if sel_axes(12)
                    hca = irf_panel('PAS DEF');
                    irf_spectrogram(hca,app.DATA.iDEF.specrec,'log');
                    set(hca, 'YScale', 'log');
                    colormap(hca,jet)
                    ylabel(hca,'Energy [eV]');
                    set(gca,'linewidth',1.8)
                    set(gca ,'Layer', 'Top')
                    set(gca,'TickDir','in')
                    grid(hca,'off')
                end


                if sel_axes(13)
                    hca = irf_panel('elipticity');
                    irf_spectrogram(hca,app.DATA.SVD.specrec_el,'lin');
                    set(hca, 'YScale', 'log');
                    crr = interp1([1 64 128 192 256],[0.0  0.5 0.75 1.0 0.75],1:256);
                    cgg = interp1([1 64 128 192 256],[0.0  0.5 0.75 0.5 0.00],1:256);
                    cbb = interp1([1 64 128 192 256],[0.75 1.0 0.75 0.5 0.00],1:256);
                    bgrcmap = [crr' cgg' cbb'];
                    colormap(hca,bgrcmap);
                    caxis(hca,[-1 1])


                    hca = irf_panel('thkb');
                    irf_spectrogram(hca,app.DATA.SVD.specrec_thkb,'lin');
                    set(hca, 'YScale', 'log');
                    caxis(hca,[0 90])
                    set(gca,'linewidth',1.8)
                    set(gca ,'Layer', 'Top')
                    set(gca,'TickDir','in')
                    grid(hca,'off')
                end


                if sel_axes(15)
                    hca = irf_panel('pflux');
                    irf_spectrogram(hca,app.DATA.SVD.specrec_pflux,'log');
                    set(hca, 'YScale', 'log');
                    crr = interp1([1 64 128 192 256],[0.0  0.5 0.75 1.0 0.75],1:256);
                    cgg = interp1([1 64 128 192 256],[0.0  0.5 0.75 0.5 0.00],1:256);
                    cbb = interp1([1 64 128 192 256],[0.75 1.0 0.75 0.5 0.00],1:256);
                    bgrcmap = [crr' cgg' cbb'];
                    colormap(hca,bgrcmap);
                    caxis(hca,[-1 1])
                    set(gca,'linewidth',1.8)
                    set(gca ,'Layer', 'Top')
                    set(gca,'TickDir','in')
                    grid(hca,'off')
                end


                ss = get(0,'ScreenSize');
                set(gcf,'position',[1 1 800 ss(4)])

                axis(h,'tight')
                irf_plot_axis_align(h)
                irf_zoom(h,'x',Tint)
                set(h,'linewidth',1.5)

                for k=1:sum(sel_axes)-1
                    set(h(k),'xticklabels',[]);
                end
                set(h,'fontsize',15)


                app.StatusEditField.Value = 'Done';

            else
                app.StatusEditField.Value = 'NO DATA TO PLOT';
            end


        end

        % Button pushed function: MVAGUIButton
        function MVAGUIButtonPushed(app, event)

            if sum(double([(app.BCheckBox.Value==1 || app.BxyzCheckBox.Value==1),sum(double(app.BLamp.Color==[0 1 0]))==3]))==2

                Tint2 = irf.tint(app.AnalysisintervalEditField.Value);

                if app.mvafilterCheckBox.Value
                    irf_minvar_gui(irf_filt(app.DATA.BSCM.tlim(Tint2),app.mvaminfreq.Value,app.mvamaxfreq.Value,[],5));
                else
                    irf_minvar_gui(app.DATA.BFGM.tlim(Tint2));
                end

                app.StatusEditField.Value = 'IRF minvar GUI';

            end

        end

        % Button pushed function: ccButton
        function ccButtonPushed(app, event)

            app.ToDatePicker.Value = app.FromDatePicker.Value;

        end

        % Button pushed function: dayButton
        function dayButtonPushed(app, event)


            if ~isempty(app.TintEditField.Value)
                TT = irf.tint(app.TintEditField.Value);
                TT1 = datevec(irf_time(TT(1),'epochtt>date'));
                TT2 = datevec(irf_time(TT(2),'epochtt>date'));
                app.FromDatePicker.Value = datetime(TT1(1:3));
                app.ToDatePicker.Value   = datetime(TT2(1:3));
                app.HHEditField.Value = TT1(4);
                app.MMEditField.Value = TT1(5);
                app.SSEditField.Value = TT1(6);

                app.HHEditField_2.Value = TT2(4);
                app.MMEditField_2.Value = TT2(5);
                app.SSEditField_2.Value = TT2(6);
            else
                error('At least Tint string needed')
            end


            dstart = datevec(app.FromDatePicker.Value);
            dstart(4:6) = [app.HHEditField.Value app.MMEditField.Value app.SSEditField.Value];

            dend = datevec(app.ToDatePicker.Value);
            dend(4:6) = [app.HHEditField_2.Value app.MMEditField_2.Value app.SSEditField_2.Value];


            dnew_start = datevec(datenum(dstart)+1);
            dnew_end = datevec(datenum(dend)+1);

            app.FromDatePicker.Value = datetime(dnew_start(1:3));
            app.ToDatePicker.Value   = datetime(dnew_end(1:3));

            app.HHEditField.Value = dnew_start(4);
            app.MMEditField.Value = dnew_start(5);
            app.SSEditField.Value = dnew_start(6);

            app.HHEditField_2.Value = dnew_end(4);
            app.MMEditField_2.Value = dnew_end(5);
            app.SSEditField_2.Value = dnew_end(6);

            app.TintEditField.Value = [datestr(app.FromDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField.Value,app.MMEditField.Value,app.SSEditField.Value],'HH:MM:SS.000Z'),'/',datestr(app.ToDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField_2.Value,app.MMEditField_2.Value,app.SSEditField_2.Value],'HH:MM:SS.000Z')];
            app.AnalysisintervalEditField.Value = [datestr(app.FromDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField.Value,app.MMEditField.Value,app.SSEditField.Value],'HH:MM:SS.000Z'),'/',datestr(app.ToDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField_2.Value,app.MMEditField_2.Value,app.SSEditField_2.Value],'HH:MM:SS.000Z')];


        end

        % Value changing function: TintEditField
        function TintEditFieldValueChanging(app, event)
            changingValue = event.Value;
        end

        % Button pushed function: copyTintButton
        function copyTintButtonPushed(app, event)
            app.AnalysisintervalEditField.Value = app.TintEditField.Value;
        end

        % Button pushed function: SavedatatoworkspaceButton
        function SavedatatoworkspaceButtonPushed(app, event)

            assignin('base','DATA',app.DATA)


        end

        % Button pushed function: db_intitButton
        function db_intitButtonPushed(app, event)
            Example_SolO_db_init
        end

        % Button pushed function: PowerspectraButton
        function PowerspectraButtonPushed(app, event)

            app.StatusEditField.Value = 'Wait...';
            pause(0.5)
            if strcmp(app.PSPEC_Switch.Value,'SCM')


                if sum(double(app.BSCMLamp.Color==[0 1 0]))==3
                    spec_avail = 1;
                    psec_dat = app.DATA.BSCM;
                else
                    spec_avail = 0;
                    warning('B data not available - check plotting tab')
                end

            end

            if strcmp(app.PSPEC_Switch.Value,'FGM')

                if sum(double(app.BLamp.Color==[0 1 0]))==3
                    spec_avail = 1;
                    psec_dat = app.DATA.BFGM;
                else
                    spec_avail = 0;
                    warning('B data not available - check plotting tab')
                end

            end

            if spec_avail==1

                if strcmp(app.coordDropDown.Value,'SRF')
                    if strcmp(psec_dat.coordinateSystem,'RTN')
                        app.StatusEditField.Value = 'Converting RTN>SRF';
                        pause(0.5)
                        psec_dat = solo.srf2rtn(psec_dat, -1); %RTN>SRF
                    end
                end

                if strcmp(app.coordDropDown.Value,'RTN')
                    if strcmp(psec_dat.coordinateSystem,'SRF')
                        app.StatusEditField.Value = 'Converting SRF>RTN';
                        pause(0.5)
                        psec_dat = solo.srf2rtn(psec_dat); % SRF>RTN
                    end
                end


                if strcmp(app.coordDropDown.Value,'fac')

                    if sum(double(app.BLamp.Color==[0 1 0]))==3 && strcmp(app.PSPEC_Switch.Value,'SCM')

                        if strcmp(psec_dat.coordinateSystem,'SRF')


                            if strcmp(app.DATA.BFGM,'SRF')
                                fac = irf_convert_fac(psec_dat,app.DATA.BFGM,[1 0 0]);
                            else
                                fac = irf_convert_fac(psec_dat,solo.srf2rtn(app.DATA.BFGM, -1),[1 0 0]);
                            end


                        else

                            if strcmp(app.DATA.BFGM,'RTN')
                                fac = irf_convert_fac(psec_dat,app.DATA.BFGM,[1 0 0]);
                            else
                                fac = irf_convert_fac(psec_dat,solo.srf2rtn(app.DATA.BFGM),[1 0 0]);
                            end
                        end


                        fac_yes = 1;
                    else
                        app.StatusEditField.Value = 'FGM needed for FAC / select SCM';
                        pause(0.5)
                        fac_yes = 0;
                    end
                end

                tttt = irf_time(psec_dat.time,'epochtt>tt');
                fs=1/(tttt(2)-tttt(1));

                if ~strcmp(app.coordDropDown.Value,'fac')

                    if app.pspecfilterCheckBox.Value
                        psec_dat = irf_filt(psec_dat,app.fminHzEditField.Value,app.fmaxHzEditField.Value,fs,5);
                    end

                    if ~strcmp(app.coordDropDown.Value,'abs')
                        figure
                        [p1, f1] = pwelch(psec_dat.data(:,1),[],[],[],round(fs,3));
                        [p2, f2] = pwelch(psec_dat.data(:,2),[],[],[],round(fs,3));
                        [p3, f3] = pwelch(psec_dat.data(:,3),[],[],[],round(fs,3));
                        loglog(f1,p1,'k')
                        hold on
                        loglog(f2,p2,'r')
                        loglog(f3,p3,'b')
                        axis square
                        set(gcf,'color','w')
                        legend('x','y','z')
                        xlabel('f')
                        ylabel('P')
                    else
                        figure
                        [p1, f1] = pwelch(psec_dat.abs.data,[],[],[],round(fs,3));
                        loglog(f1,p1,'k')
                        axis square
                        set(gcf,'color','w')
                        legend('|B|')
                        xlabel('f')
                        ylabel('P')
                    end


                    if app.pspecfilterCheckBox.Value
                        if app.fminHzEditField.Value==0
                            pspecxmin = 1;
                        else
                            pspecxmin = app.fminHzEditField.Value;
                        end
                        if app.fmaxHzEditField.Value==0
                            pspecxmax = fs/2;
                        else
                            pspecxmax = app.fmaxHzEditField.Value;
                        end
                        xlim([pspecxmin pspecxmax])
                    else
                        xlim([0 fs/2])
                    end


                else
                    if fac_yes

                        if app.pspecfilterCheckBox.Value
                            fac = irf_filt(fac.data,app.app.fminHzEditField.Value,app.app.fmaxHzEditField.Value,fs,5);
                        end

                        figure
                        [p1, f1] = pwelch(fac.data(:,1),[],[],[],round(fs,3));
                        [p2, f2] = pwelch(fac.data(:,2),[],[],[],round(fs,3));
                        [p3, f3] = pwelch(fac.data(:,3),[],[],[],round(fs,3));
                        loglog(f1,p1,'k')
                        hold on
                        loglog(f2,p2,'r')
                        loglog(f3,p3,'b')
                        axis square
                        set(gcf,'color','w')
                        legend('perp1','perp2','para')
                        xlabel('f')
                        ylabel('P')


                        if app.pspecfilterCheckBox.Value
                            if app.fminHzEditField.Value==0
                                pspecxmin = 1;
                            else
                                pspecxmin = app.fminHzEditField.Value;
                            end
                            if app.fmaxHzEditField.Value==0
                                pspecxmax = fs/2;
                            else
                                pspecxmax = app.fmaxHzEditField.Value;
                            end
                            xlim([pspecxmin pspecxmax])
                        else
                            if strcmp(app.PSPEC_Switch.Value,'SCM')
                                xlim([1 fs/2])
                            else
                                xlim([0 fs/2])
                            end
                        end

                    end
                end

                app.StatusEditField.Value = 'Done';
                pause(0.5)
            else
                app.StatusEditField.Value = 'Data not available for PSPEC';
            end


        end

        % Value changed function: VRPWCheckBox
        function VRPWCheckBoxValueChanged(app, event)
            value = app.VRPWCheckBox.Value;

        end

        % Button pushed function: CalcButton
        function CalcButtonPushed(app, event)


            n = app.nccEditField.Value;
            Ti = app.TieVEditField.Value;
            Te = app.TeeVEditField.Value;
            B = app.BnTEditField.Value;

            if strcmp(app.SelectparameterDropDown.Value,'Debye_length_[m]')
                app.calc_field.Value = 7.43*sqrt(Te/n);
            end

            if strcmp(app.SelectparameterDropDown.Value,'e-_plasma_f_[kHz]')
                app.calc_field.Value = 9*sqrt(n);
            end

            if strcmp(app.SelectparameterDropDown.Value,'e-_gyrof_[Hz]')
                app.calc_field.Value = 28*B;
            end

            if strcmp(app.SelectparameterDropDown.Value,'e-_gyrorad_[km]')
                app.calc_field.Value = 3.4*sqrt(Te) / B;
            end

            if strcmp(app.SelectparameterDropDown.Value,'e-_inert_l_[km]')
                app.calc_field.Value = 5.3 / sqrt(n);
            end

            if strcmp(app.SelectparameterDropDown.Value,'H+_plasma_f_[Hz]')
                app.calc_field.Value = 210 *sqrt(n);
            end

            if strcmp(app.SelectparameterDropDown.Value,'H+_gyrof_[Hz]')
                app.calc_field.Value = 0.0153* B;
            end

            if strcmp(app.SelectparameterDropDown.Value,'H+_gyrorad_[km]')
                app.calc_field.Value = 144*sqrt(Ti) / B;
            end

            if strcmp(app.SelectparameterDropDown.Value,'lower_hybr_f_[Hz]')
                app.calc_field.Value = sqrt(0.427*B^2 /(1+ 9.7e-6 * (B)^2)+2.3e-4 *n);
            end

            if strcmp(app.SelectparameterDropDown.Value,'Alfven_veloc_[km/s]')
                app.calc_field.Value = 22*B /sqrt(n);
            end

            if strcmp(app.SelectparameterDropDown.Value,'H+_inert_l_[km]')
                app.calc_field.Value = 230 / sqrt(n);
            end

            if strcmp(app.SelectparameterDropDown.Value,'Beta_i')
                app.calc_field.Value = 0.4*n*Ti/B^2;
            end


        end

        % Button pushed function: hrButton
        function hrButtonPushed(app, event)


            if ~isempty(app.TintEditField.Value)

                TT = irf.tint(app.TintEditField.Value);
                TT1 = datevec(irf_time(TT(1),'epochtt>date'));
                TT2 = datevec(irf_time(TT(2),'epochtt>date'));
                app.FromDatePicker.Value = datetime(TT1(1:3));
                app.ToDatePicker.Value   = datetime(TT2(1:3));
                app.HHEditField.Value = TT1(4);
                app.MMEditField.Value = TT1(5);
                app.SSEditField.Value = TT1(6);

                app.HHEditField_2.Value = TT2(4);
                app.MMEditField_2.Value = TT2(5);
                app.SSEditField_2.Value = TT2(6);
            else
                error('At least Tint string needed')
            end


            dstart = datevec(app.FromDatePicker.Value);
            dstart(4:6) = [app.HHEditField.Value app.MMEditField.Value app.SSEditField.Value];

            dend = datevec(app.ToDatePicker.Value);
            dend(4:6) = [app.HHEditField_2.Value app.MMEditField_2.Value app.SSEditField_2.Value];


            dnew_start = datevec(datenum(dstart)+0.5);
            dnew_end = datevec(datenum(dend)+0.5);

            app.FromDatePicker.Value = datetime(dnew_start(1:3));
            app.ToDatePicker.Value   = datetime(dnew_end(1:3));

            app.HHEditField.Value = dnew_start(4);
            app.MMEditField.Value = dnew_start(5);
            app.SSEditField.Value = dnew_start(6);

            app.HHEditField_2.Value = dnew_end(4);
            app.MMEditField_2.Value = dnew_end(5);
            app.SSEditField_2.Value = dnew_end(6);

            app.TintEditField.Value = [datestr(app.FromDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField.Value,app.MMEditField.Value,app.SSEditField.Value],'HH:MM:SS.000Z'),'/',datestr(app.ToDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField_2.Value,app.MMEditField_2.Value,app.SSEditField_2.Value],'HH:MM:SS.000Z')];
            app.AnalysisintervalEditField.Value = [datestr(app.FromDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField.Value,app.MMEditField.Value,app.SSEditField.Value],'HH:MM:SS.000Z'),'/',datestr(app.ToDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField_2.Value,app.MMEditField_2.Value,app.SSEditField_2.Value],'HH:MM:SS.000Z')];


        end

        % Button pushed function: hrButton_2
        function hrButton_2Pushed(app, event)


            if ~isempty(app.TintEditField.Value)
                TT = irf.tint(app.TintEditField.Value);
                TT1 = datevec(irf_time(TT(1),'epochtt>date'));
                TT2 = datevec(irf_time(TT(2),'epochtt>date'));
                app.FromDatePicker.Value = datetime(TT1(1:3));
                app.ToDatePicker.Value   = datetime(TT2(1:3));
                app.HHEditField.Value = TT1(4);
                app.MMEditField.Value = TT1(5);
                app.SSEditField.Value = TT1(6);

                app.HHEditField_2.Value = TT2(4);
                app.MMEditField_2.Value = TT2(5);
                app.SSEditField_2.Value = TT2(6);
            else
                error('At least Tint string needed')
            end


            dstart = datevec(app.FromDatePicker.Value);
            dstart(4:6) = [app.HHEditField.Value app.MMEditField.Value app.SSEditField.Value];

            dend = datevec(app.ToDatePicker.Value);
            dend(4:6) = [app.HHEditField_2.Value app.MMEditField_2.Value app.SSEditField_2.Value];

            dnew_start = datevec(datenum(dstart)+0.25);
            dnew_end = datevec(datenum(dend)+0.25);

            app.FromDatePicker.Value = datetime(dnew_start(1:3));
            app.ToDatePicker.Value   = datetime(dnew_end(1:3));

            app.HHEditField.Value = dnew_start(4);
            app.MMEditField.Value = dnew_start(5);
            app.SSEditField.Value = dnew_start(6);

            app.HHEditField_2.Value = dnew_end(4);
            app.MMEditField_2.Value = dnew_end(5);
            app.SSEditField_2.Value = dnew_end(6);

            app.TintEditField.Value = [datestr(app.FromDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField.Value,app.MMEditField.Value,app.SSEditField.Value],'HH:MM:SS.000Z'),'/',datestr(app.ToDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField_2.Value,app.MMEditField_2.Value,app.SSEditField_2.Value],'HH:MM:SS.000Z')];
            app.AnalysisintervalEditField.Value = [datestr(app.FromDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField.Value,app.MMEditField.Value,app.SSEditField.Value],'HH:MM:SS.000Z'),'/',datestr(app.ToDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField_2.Value,app.MMEditField_2.Value,app.SSEditField_2.Value],'HH:MM:SS.000Z')];

        end

        % Button pushed function: hrButton_3
        function hrButton_3Pushed(app, event)


            if ~isempty(app.TintEditField.Value)
                TT = irf.tint(app.TintEditField.Value);
                TT1 = datevec(irf_time(TT(1),'epochtt>date'));
                TT2 = datevec(irf_time(TT(2),'epochtt>date'));
                app.FromDatePicker.Value = datetime(TT1(1:3));
                app.ToDatePicker.Value   = datetime(TT2(1:3));
                app.HHEditField.Value = TT1(4);
                app.MMEditField.Value = TT1(5);
                app.SSEditField.Value = TT1(6);

                app.HHEditField_2.Value = TT2(4);
                app.MMEditField_2.Value = TT2(5);
                app.SSEditField_2.Value = TT2(6);
            else
                error('At least Tint string needed')
            end


            dstart = datevec(app.FromDatePicker.Value);
            dstart(4:6) = [app.HHEditField.Value app.MMEditField.Value app.SSEditField.Value];

            dend = datevec(app.ToDatePicker.Value);
            dend(4:6) = [app.HHEditField_2.Value app.MMEditField_2.Value app.SSEditField_2.Value];


            dnew_start = datevec(datenum(dstart)+(2/24));
            dnew_end = datevec(datenum(dend)+(2/24));

            app.FromDatePicker.Value = datetime(dnew_start(1:3));
            app.ToDatePicker.Value   = datetime(dnew_end(1:3));

            app.HHEditField.Value = dnew_start(4);
            app.MMEditField.Value = dnew_start(5);
            app.SSEditField.Value = dnew_start(6);

            app.HHEditField_2.Value = dnew_end(4);
            app.MMEditField_2.Value = dnew_end(5);
            app.SSEditField_2.Value = dnew_end(6);

            app.TintEditField.Value = [datestr(app.FromDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField.Value,app.MMEditField.Value,app.SSEditField.Value],'HH:MM:SS.000Z'),'/',datestr(app.ToDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField_2.Value,app.MMEditField_2.Value,app.SSEditField_2.Value],'HH:MM:SS.000Z')];
            app.AnalysisintervalEditField.Value = [datestr(app.FromDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField.Value,app.MMEditField.Value,app.SSEditField.Value],'HH:MM:SS.000Z'),'/',datestr(app.ToDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField_2.Value,app.MMEditField_2.Value,app.SSEditField_2.Value],'HH:MM:SS.000Z')];

        end

        % Button pushed function: hrButton_4
        function hrButton_4Pushed(app, event)


            if ~isempty(app.TintEditField.Value)
                TT = irf.tint(app.TintEditField.Value);
                TT1 = datevec(irf_time(TT(1),'epochtt>date'));
                TT2 = datevec(irf_time(TT(2),'epochtt>date'));
                app.FromDatePicker.Value = datetime(TT1(1:3));
                app.ToDatePicker.Value   = datetime(TT2(1:3));
                app.HHEditField.Value = TT1(4);
                app.MMEditField.Value = TT1(5);
                app.SSEditField.Value = TT1(6);

                app.HHEditField_2.Value = TT2(4);
                app.MMEditField_2.Value = TT2(5);
                app.SSEditField_2.Value = TT2(6);
            else
                error('At least Tint string needed')
            end


            dstart = datevec(app.FromDatePicker.Value);
            dstart(4:6) = [app.HHEditField.Value app.MMEditField.Value app.SSEditField.Value];

            dend = datevec(app.ToDatePicker.Value);
            dend(4:6) = [app.HHEditField_2.Value app.MMEditField_2.Value app.SSEditField_2.Value];


            dnew_start = datevec(datenum(dstart)+(1/24));
            dnew_end = datevec(datenum(dend)+(1/24));

            app.FromDatePicker.Value = datetime(dnew_start(1:3));
            app.ToDatePicker.Value   = datetime(dnew_end(1:3));

            app.HHEditField.Value = dnew_start(4);
            app.MMEditField.Value = dnew_start(5);
            app.SSEditField.Value = dnew_start(6);

            app.HHEditField_2.Value = dnew_end(4);
            app.MMEditField_2.Value = dnew_end(5);
            app.SSEditField_2.Value = dnew_end(6);

            app.TintEditField.Value = [datestr(app.FromDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField.Value,app.MMEditField.Value,app.SSEditField.Value],'HH:MM:SS.000Z'),'/',datestr(app.ToDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField_2.Value,app.MMEditField_2.Value,app.SSEditField_2.Value],'HH:MM:SS.000Z')];
            app.AnalysisintervalEditField.Value = [datestr(app.FromDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField.Value,app.MMEditField.Value,app.SSEditField.Value],'HH:MM:SS.000Z'),'/',datestr(app.ToDatePicker.Value,'yyyy-mm-ddT'),datestr([2000,0,0,app.HHEditField_2.Value,app.MMEditField_2.Value,app.SSEditField_2.Value],'HH:MM:SS.000Z')];

        end

        % Value changed function: TimeUTCEditField
        function TimeUTCEditFieldValueChanged(app, event)

        end

        % Button pushed function: plot_vdfButton
        function plot_vdfButtonPushed(app, event)

            if isempty(app.vdfs)
                Tint = irf.tint( [irf_time(app.TimeUTCEditField.Value,'utc>utc_yyyy-mm-dd') 'T00:00:00.00Z/' irf_time(app.TimeUTCEditField.Value,'utc>utc_yyyy-mm-dd') 'T23:59:59.00Z']);
                pause(0.5)
                app.StatusEditField_2.Value = 'Reading data and plotting: can take a while';
                pause(0.5)
                app.vdfs = solo.get_data('pas_vdf',Tint);
            else
                ch1 = irf_time(app.TimeUTCEditField.Value,'utc>doy');
                ch2 = irf_time(app.vdfs.(char(fieldnames(app.vdfs))).time(1),'epochtt>doy');
                if sum(double(ch1==ch2))~=2
                    Tint = irf.tint( [irf_time(app.TimeUTCEditField.Value,'utc>utc_yyyy-mm-dd') 'T00:00:00.00Z/' irf_time(app.TimeUTCEditField.Value,'utc>utc_yyyy-mm-dd') 'T23:59:59.00Z']);
                    pause(0.5)
                    app.StatusEditField_2.Value = 'Reading data and plotting: can take a while';
                    pause(0.5)
                    app.vdfs = solo.get_data('pas_vdf',Tint);
                end
            end


            if ~isempty(app.vdfs)

                pause(0.5)
                app.StatusEditField_2.Value = 'VDF file loaded';
                pause(0.5)

                Tint_vdf = irf.tint([app.TimeUTCEditField.Value '/' irf_time(app.TimeUTCEditField.Value,'utc>utc_yyyy-mm-dd') 'T23:59:59.00Z']);

                pause(0.5)
                app.VDFdateEditField.Value = irf_time(Tint_vdf(1),'epochtt>utc_yyyy/mm/dd');
                pause(0.5)


                PDout_ = app.vdfs.(char(fieldnames(app.vdfs))).tlim(Tint_vdf);
                PDout = PDout_(1:app.nAvEditField.Value);


                pause(0.5)
                app.VDFintervalEditField.Value=[irf_time(PDout_.time(1),'epochtt>utc') ' > ' irf_time(PDout_.time(app.nAvEditField.Value),'epochtt>utc')];
                %app.VDFintervalEditField.Value =
                pause(0.5)

                SCpot = irf.ts_scalar(PDout.time,zeros(size(PDout.time)));
                Ng = 600; nMC = 1e3; vg = linspace(-15e2,15e2,Ng);

                Tint_B = irf.tint([irf_time(irf_time(Tint_vdf(1),'epochTT>date')-5/(24*60*60),'date>utc') '/'  irf_time(irf_time(Tint_vdf(1),'epochTT>date')+5/(24*60*60),'date>utc')]);
                BSRF = solo.get_data('B_SRF_norm',Tint_B);
                B0 = mean(BSRF.data);
                B0 = B0/norm(B0);
                B_para = B0/norm(B0);
                B_perp2 = cross(B_para,[1 0 0]);
                B_perp1 = cross(B_perp2,B_para);

                moments = mms.psd_moments(PDout,SCpot);

                Tint_qf = irf.tint([irf_time(irf_time(Tint_vdf(1),'epochTT>date')-5/(24*60*60),'date>utc') '/'  irf_time(irf_time(Tint_vdf(1),'epochTT>date')+5/(24*60*60),'date>utc')]);
                qf = solo.get_data('pas_qf',Tint_qf);
                vdf_qual = mean(qf.data);

                V0 = moments.V_psd.data;

                if app.EminEditField.Value~=0 || app.EmaxEditField.Value~=0
                    erange = [app.EminEditField.Value app.EmaxEditField.Value];
                    PDout = PDout.elim(erange);
                end

                switch app.CoordaintesDropDown.Value
                    case 'SRF'
                        f2Dxy = PDout.reduce('2D',[1 0 0],[0 1 0],'base','cart','vg',vg,'nMC',nMC,'SCpot',SCpot,'vint',[-1500 1500]);
                        f2Dxz = PDout.reduce('2D',[1 0 0],[0 0 1],'base','cart','vg',vg,'nMC',nMC,'SCpot',SCpot,'vint',[-1500 1500]);
                        f2Dyz = PDout.reduce('2D',[0 1 0],[0 0 1],'base','cart','vg',vg,'nMC',nMC,'SCpot',SCpot,'vint',[-1500 1500]);
                        if app.RemovebackgroundvelocityCheckBox.Value
                            Voff_xy = [V0(1) V0(2)];
                            Voff_xz = [V0(1) V0(3)];
                            Voff_yz = [V0(2) V0(3)];
                        else
                            Voff_xy = [0 0];
                            Voff_xz = [0 0];
                            Voff_yz = [0 0];
                        end
                        lab_1 = 'Vx SRF [km/s]';
                        lab_2 = 'Vy SRF [km/s]';
                        lab_3 = 'Vz SRF [km/s]';


                    case 'FAC'
                        f2Dxy = PDout.reduce('2D',B_para,B_perp1,'base','cart','vg',vg,'nMC',nMC,'SCpot',SCpot,'vint',[-1500 1500]);
                        f2Dxz = PDout.reduce('2D',B_para,B_perp2,'base','cart','vg',vg,'nMC',nMC,'SCpot',SCpot,'vint',[-1500 1500]);
                        f2Dyz = PDout.reduce('2D',B_perp1,B_perp2,'base','cart','vg',vg,'nMC',nMC,'SCpot',SCpot,'vint',[-1500 1500]);

                        lab_1 = 'V$\parallel$ [km/s]';
                        lab_2 = 'V$\perp1$ [km/s]';
                        lab_3 = 'V$\perp2$ [km/s]';

                        if app.RemovebackgroundvelocityCheckBox.Value
                            Voff_xy = [dot(V0,B_para),dot(V0,B_perp1)];
                            Voff_xz = [dot(V0,B_para),dot(V0,B_perp2)];
                            Voff_yz = [dot(V0,B_perp1),dot(V0,B_perp2)];
                        else
                            Voff_xy = [0 0];
                            Voff_xz = [0 0];
                            Voff_yz = [0 0];
                        end
                end

                h = figure(999);
                dist_surf = f2Dxy.plot_plane(h,'docolorbar',0);
                XX_xy = dist_surf.XData(1:600)-Voff_xy(1);
                YY_xy = dist_surf.YData(1:600)-Voff_xy(2);
                CC_xy = dist_surf.CData;
                close(h); clear dist_surf
                h = figure(999);
                dist_surf = f2Dxz.plot_plane(h,'docolorbar',0);
                XX_xz = dist_surf.XData(1:600)-Voff_xz(1);
                YY_xz = dist_surf.YData(1:600)-Voff_xz(2);
                CC_xz = dist_surf.CData;
                close(h); clear dist_surf
                h = figure(999);
                dist_surf = f2Dyz.plot_plane(h,'docolorbar',0);
                XX_yz = dist_surf.XData(1:600)-Voff_yz(1);
                YY_yz = dist_surf.YData(1:600)-Voff_yz(2);
                CC_yz = dist_surf.CData;
                close(h); clear dist_surf

                fn=figure;
                xwidth = 0.25;
                ywidth = 0.75;

                set(fn,'Position',[44 1 1848 591])
                h(1)=axes('position',[0.06 0.18 xwidth ywidth]); % [x y dx dy]
                h(2)=axes('position',[0.39 0.18 xwidth ywidth]);
                h(3)=axes('position',[0.72 0.18 xwidth ywidth]);
                ud=get(fn,'userdata');
                ud.subplot_handles=h;
                set(fn,'userdata',ud);
                set(fn,'defaultLineLineWidth',1);

                surf(h(1),XX_xy,YY_xy,CC_xy);
                shading(h(1),'flat')
                view(h(1),2)
                caxis(h(1),[-7   -1]);
                hcb = colorbar(h(1));
                grid(h(1),'off')
                axis(h(1),'equal')
                colormap(h(1),'jet')
                irf_legend(h(1),'(a)',[0.98 0.98],'color','k','fontsize',25)
                xlabel(h(1),lab_1,'interpreter','latex','fontsize',15)
                ylabel(h(1),lab_2,'interpreter','latex','fontsize',15)
                title(h(1),f2Dxy.time.toUtc)
                box(h(1))
                set(h(1),'linewidth',1.5)
                set(h(1),'XMinorTick','on','YMinorTick','on')

                surf(h(2),XX_xz,YY_xz,CC_xz);
                shading(h(2),'flat')
                view(h(2),2)
                caxis(h(2),[-7   -1]);
                hcb = colorbar(h(2));
                grid(h(2),'off')
                axis(h(2),'equal')
                colormap(h(2),'jet')
                irf_legend(h(2),'(b)',[0.98 0.98],'color','k','fontsize',25)
                xlabel(h(2),lab_1,'interpreter','latex','fontsize',15)
                ylabel(h(2),lab_3,'interpreter','latex','fontsize',15)
                title(h(2),['Quality factor: ' num2str(vdf_qual)])
                box(h(2))
                set(h(2),'linewidth',1.5)
                set(h(2),'XMinorTick','on','YMinorTick','on')

                surf(XX_yz,YY_yz,CC_yz);
                shading(h(3),'flat')
                view(h(3),2)
                caxis(h(3),[-7   -1]);
                hcb = colorbar(h(3));
                ylabel(hcb,'log_{10} f_i (s^2 m^{-5})','interpreter','tex','fontsize',15)
                grid(h(3),'off')
                axis(h(3),'equal')
                colormap(h(3),'jet')
                irf_legend(h(3),'(c)',[0.98 0.98],'color','k','fontsize',25)
                xlabel(h(3),lab_2,'interpreter','latex','fontsize',15)
                ylabel(h(3),lab_3,'interpreter','latex','fontsize',15)

                title(h(3),['V SRF = [' num2str(round(moments.V_psd.data(1))) ' ' num2str(round(moments.V_psd.data(2))) ' ' num2str(round(moments.V_psd.data(3))) '] km/s, T = ' num2str(round(moments.T_psd.data(1))) ' eV, Ni = ' num2str(round(moments.n_psd.data(1))) 'cc'])
                box(gca)
                set(h(3),'linewidth',1.5)
                set(h(3),'XMinorTick','on','YMinorTick','on')

                set(h(1:3),'fontsize',15)
                set(gcf,'color','w');
            else
                pause(0.5)
                app.StatusEditField_2.Value = 'no VDF file found';
                pause(0.5)
            end

        end

        % Button pushed function: LaunchshockGUIButton
        function LaunchshockGUIButtonPushed(app, event)


            Tint = irf.tint(app.AnalysisintervalEditField.Value);

            sh.B = solo.get_data('B_rtn_norm',Tint);
            sh.V = solo.get_data('Vi_rtn',Tint);
            sh.n = solo.get_data('Ni',Tint);

            if ~isempty(sh.B) && ~isempty(sh.V) && ~isempty(sh.n)
                irf_shock_gui(sh)
                app.StatusEditField.Value = 'IRF shock GUI';
            else
                app.StatusEditField.Value = 'Data not available for IRF shock GUI';
            end


        end

        % Button pushed function: standardplotButton
        function standardplotButtonPushed(app, event)
            
app.BxyzCheckBox.Value=1;
app.BCheckBox.Value=1;
app.VCheckBox.Value=1;
app.TtotCheckBox.Value=1;
app.IonDEFspectrogramCheckBox.Value=1;
app.DensityCheckBox.Value=1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlottingTab = uitab(app.TabGroup);
            app.PlottingTab.Title = 'Plotting';
            app.ccButton = uibutton(app.PlottingTab, 'push');
            app.ccButton.ButtonPushedFcn = createCallbackFcn(app, @ccButtonPushed, true);
            app.ccButton.Text = 'cc';
            app.dayButton = uibutton(app.PlottingTab, 'push');
            app.dayButton.ButtonPushedFcn = createCallbackFcn(app, @dayButtonPushed, true);
            app.dayButton.Text = '+1day';
            app.ImportdateButton = uibutton(app.PlottingTab, 'push');
            app.ImportdateButton.ButtonPushedFcn = createCallbackFcn(app, @ImportdateButtonPushed, true);
            app.ImportdateButton.Text = 'Import date';
            app.HHEditField = uieditfield(app.PlottingTab, 'numeric');
            app.HHEditFieldLabel = uilabel(app.PlottingTab);
            app.HHEditFieldLabel.Text = 'HH';
            app.MMEditField = uieditfield(app.PlottingTab, 'numeric');
            app.MMEditFieldLabel = uilabel(app.PlottingTab);
            app.MMEditFieldLabel.Text = 'MM';
            app.SSEditField = uieditfield(app.PlottingTab, 'numeric');
            app.SSEditFieldLabel = uilabel(app.PlottingTab);
            app.SSEditFieldLabel.Text = 'SS';
            app.HHEditField_2 = uieditfield(app.PlottingTab, 'numeric');
            app.HHEditField_2Label = uilabel(app.PlottingTab);
            app.HHEditField_2Label.Text = 'HH';
            app.MMEditField_2 = uieditfield(app.PlottingTab, 'numeric');
            app.MMEditField_2Label = uilabel(app.PlottingTab);
            app.MMEditField_2Label.Text = 'MM';
            app.SSEditField_2 = uieditfield(app.PlottingTab, 'numeric');
            app.SSEditField_2Label = uilabel(app.PlottingTab);
            app.SSEditField_2Label.Text = 'SS';
            app.FromDatePicker = uidatepicker(app.PlottingTab);
            app.FromDatePicker.Limits = [datetime([2020 3 1]) datetime([2030 12 31])];
            app.FromDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.FromDatePickerLabel = uilabel(app.PlottingTab);
            app.FromDatePickerLabel.Text = 'From';
            app.ToDatePicker = uidatepicker(app.PlottingTab);
            app.ToDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.ToDatePickerLabel = uilabel(app.PlottingTab);
            app.ToDatePickerLabel.Text = 'To';
            app.TintEditField = uieditfield(app.PlottingTab, 'text');
            app.TintEditField.ValueChangedFcn = createCallbackFcn(app, @TintEditFieldValueChanged, true);
            app.TintEditField.ValueChangingFcn = createCallbackFcn(app, @TintEditFieldValueChanging, true);
            app.TintEditFieldLabel = uilabel(app.PlottingTab);
            app.TintEditFieldLabel.Text = 'Tint';
            app.SelectdataproductsandoptionsLabel = uilabel(app.PlottingTab);
            app.SelectdataproductsandoptionsLabel.Text = 'Select data products and options';
            app.PlotdataButton = uibutton(app.PlottingTab, 'push');
            app.PlotdataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotdataButtonPushed, true);
            app.PlotdataButton.Text = 'Plot data';
            app.AvailabilityLabel = uilabel(app.PlottingTab);
            app.AvailabilityLabel.Text = 'Availability';
            app.nscpotLampLabel = uilabel(app.PlottingTab);
            app.nscpotLampLabel.Text = 'n (scpot)';
            app.nscpotLamp = uilamp(app.PlottingTab);
            app.EDCLampLabel = uilabel(app.PlottingTab);
            app.EDCLampLabel.Text = 'EDC';
            app.EDCLamp = uilamp(app.PlottingTab);
            app.VPASLampLabel = uilabel(app.PlottingTab);
            app.VPASLampLabel.Text = 'VPAS';
            app.VPASLamp = uilamp(app.PlottingTab);
            app.nPASLampLabel = uilabel(app.PlottingTab);
            app.nPASLampLabel.Text = 'n (PAS)';
            app.nPASLamp = uilamp(app.PlottingTab);
            app.BSCMLamp = uilamp(app.PlottingTab);
            app.BSCMLampLabel = uilabel(app.PlottingTab);
            app.BSCMLampLabel.Text = 'BSCM';
            app.BLamp = uilamp(app.PlottingTab);
            app.BLampLabel = uilabel(app.PlottingTab);
            app.BLampLabel.Text = 'B';
            app.StatusEditField = uieditfield(app.PlottingTab, 'text');
            app.StatusEditFieldLabel = uilabel(app.PlottingTab);
            app.StatusEditFieldLabel.Text = 'Status';
            app.SolORAUEditFieldLabel = uilabel(app.PlottingTab);
            app.SolORAUEditFieldLabel.Text = 'SolO |R| (AU)';
            app.SolORAUEditField = uieditfield(app.PlottingTab, 'numeric');
            app.VRPWLampLabel = uilabel(app.PlottingTab);
            app.VRPWLampLabel.Text = 'VRPW';
            app.VRPWLamp = uilamp(app.PlottingTab);
            app.TPASLampLabel = uilabel(app.PlottingTab);
            app.TPASLampLabel.Text = 'T (PAS)';
            app.TPASLamp = uilamp(app.PlottingTab);
            app.iDEFLampLabel = uilabel(app.PlottingTab);
            app.iDEFLampLabel.Text = 'iDEF';
            app.iDEFLamp = uilamp(app.PlottingTab);
            app.SELECTDATAINTERVALLabel = uilabel(app.PlottingTab);
            app.SELECTDATAINTERVALLabel.Text = 'SELECT DATA INTERVAL';
            app.SavedatatoworkspaceButton = uibutton(app.PlottingTab, 'push');
            app.SavedatatoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SavedatatoworkspaceButtonPushed, true);
            app.SavedatatoworkspaceButton.Text = 'Save data to workspace';
            app.db_intitButton = uibutton(app.PlottingTab, 'push');
            app.db_intitButton.ButtonPushedFcn = createCallbackFcn(app, @db_intitButtonPushed, true);
            app.db_intitButton.Text = 'db_intit';
            app.SWAPASPanel = uipanel(app.PlottingTab);
            app.SWAPASPanel.Title = 'SWA-PAS';
            app.VxyzCheckBox = uicheckbox(app.SWAPASPanel);
            app.VxyzCheckBox.Text = 'Vxyz';
            app.vpascoordSwitch = uiswitch(app.SWAPASPanel, 'slider');
            app.vpascoordSwitch.Items = {'rtn', 'srf'};
            app.vpascoordSwitch.Value = 'rtn';
            app.TtotCheckBox = uicheckbox(app.SWAPASPanel);
            app.TtotCheckBox.Text = 'Ttot';
            app.IonDEFspectrogramCheckBox = uicheckbox(app.SWAPASPanel);
            app.IonDEFspectrogramCheckBox.Text = 'Ion DEF spectrogram';
            app.perpparCheckBox = uicheckbox(app.SWAPASPanel);
            app.perpparCheckBox.Text = 'perp-par';
            app.DensityCheckBox = uicheckbox(app.SWAPASPanel);
            app.DensityCheckBox.Text = 'Density';
            app.VCheckBox = uicheckbox(app.SWAPASPanel);
            app.VCheckBox.Text = '|V|';
            app.perpparCheckBox_2 = uicheckbox(app.SWAPASPanel);
            app.perpparCheckBox_2.Text = 'perp/par';
            app.RPWPanel = uipanel(app.PlottingTab);
            app.RPWPanel.Title = 'RPW';
            app.BSCMCheckBox = uicheckbox(app.RPWPanel);
            app.BSCMCheckBox.Text = 'BSCM';
            app.SCMwavcheck = uicheckbox(app.RPWPanel);
            app.SCMwavcheck.Text = '';
            app.BSCMFramecoodDropDown = uidropdown(app.RPWPanel);
            app.BSCMFramecoodDropDown.Items = {'SRF', 'RTN', 'FAC'};
            app.BSCMFramecoodDropDown.Value = 'SRF';
            app.scmfiltCheckBox = uicheckbox(app.RPWPanel);
            app.scmfiltCheckBox.Text = 'filter';
            app.bscmwavloglin = uiswitch(app.RPWPanel, 'slider');
            app.bscmwavloglin.Items = {'log', 'lin'};
            app.bscmwavloglin.Value = 'log';
            app.nscPOTCheckBox = uicheckbox(app.RPWPanel);
            app.nscPOTCheckBox.Text = 'n (scPOT)';
            app.Ne_switch = uiswitch(app.RPWPanel, 'slider');
            app.Ne_switch.Items = {'PSP', 'L3'};
            app.Ne_switch.Value = 'PSP';
            app.EDCCOORDSwitch = uiswitch(app.RPWPanel, 'slider');
            app.EDCCOORDSwitch.Items = {'rtn', 'srf'};
            app.EDCCOORDSwitch.Value = 'rtn';
            app.EDCCheckBox = uicheckbox(app.RPWPanel);
            app.EDCCheckBox.Text = 'EDC';
            app.EDCWAVCheckBox = uicheckbox(app.RPWPanel);
            app.EDCWAVCheckBox.Text = '';
            app.EDCfilt_check = uicheckbox(app.RPWPanel);
            app.EDCfilt_check.Text = 'filter';
            app.PoyntingCheckBox = uicheckbox(app.RPWPanel);
            app.PoyntingCheckBox.Text = 'Poynting';
            app.DropDown = uidropdown(app.RPWPanel);
            app.DropDown.Items = {'As_is', 'Other'};
            app.DropDown.Value = 'As_is';
            app.EDCwavloglin = uiswitch(app.RPWPanel, 'slider');
            app.EDCwavloglin.Items = {'log', 'lin'};
            app.EDCwavloglin.Value = 'log';
            app.VRPWCheckBox = uicheckbox(app.RPWPanel);
            app.VRPWCheckBox.ValueChangedFcn = createCallbackFcn(app, @VRPWCheckBoxValueChanged, true);
            app.VRPWCheckBox.Text = 'VRPW';
            app.EDCWaveletDropDown = uidropdown(app.RPWPanel);
            app.EDCWaveletDropDown.Items = {'y,t', 'z,n', 'abs', 'sum'};
            app.EDCWaveletDropDown.Value = 'y,t';
            app.WaveletDropDown_2Label = uilabel(app.RPWPanel);
            app.WaveletDropDown_2Label.Text = 'Wavelet';
            app.WaveletDropDownLabel_2 = uilabel(app.RPWPanel);
            app.WaveletDropDownLabel_2.Text = 'Wavelet';
            app.SCMWaveletDropDown = uidropdown(app.RPWPanel);
            app.SCMWaveletDropDown.Items = {'x,r', 'y,t', 'z,n', 'abs', 'parallel', 'perp1', 'perp2'};
            app.SCMWaveletDropDown.Value = 'x,r';
            app.fminhzLabel = uilabel(app.RPWPanel);
            app.fminhzLabel.Text = 'fmin (hz)';
            app.edcfiltmin = uieditfield(app.RPWPanel, 'numeric');
            app.fmaxhzLabel_2 = uilabel(app.RPWPanel);
            app.fmaxhzLabel_2.Text = 'fmax (hz)';
            app.edcfiltmax = uieditfield(app.RPWPanel, 'numeric');
            app.fminhzLabel_2 = uilabel(app.RPWPanel);
            app.fminhzLabel_2.Text = 'fmin (hz)';
            app.bscmfiltmin = uieditfield(app.RPWPanel, 'numeric');
            app.fmaxhzLabel_3 = uilabel(app.RPWPanel);
            app.fmaxhzLabel_3.Text = 'fmax (hz)';
            app.bscmfiltmax = uieditfield(app.RPWPanel, 'numeric');
            app.SVDCheckBox_2 = uicheckbox(app.RPWPanel);
            app.SVDCheckBox_2.Text = 'SVD';
            app.MAGPanel = uipanel(app.PlottingTab);
            app.MAGPanel.Title = 'MAG';
            app.Switch = uiswitch(app.MAGPanel, 'slider');
            app.Switch.Items = {'normal', 'burst'};
            app.Switch.Value = 'normal';
            app.BCheckBox = uicheckbox(app.MAGPanel);
            app.BCheckBox.Text = '|B|';
            app.BxyzCheckBox = uicheckbox(app.MAGPanel);
            app.BxyzCheckBox.Text = 'Bxyz';
            app.Switch_2 = uiswitch(app.MAGPanel, 'slider');
            app.Switch_2.Items = {'rtn', 'srf'};
            app.Switch_2.Value = 'rtn';
            app.FGMWAVCheckBox = uicheckbox(app.MAGPanel);
            app.FGMWAVCheckBox.Text = '';
            app.SVDCheckBox = uicheckbox(app.MAGPanel);
            app.SVDCheckBox.Text = 'SVD';
            app.bfgmwavloglin = uiswitch(app.MAGPanel, 'slider');
            app.bfgmwavloglin.Items = {'log', 'lin'};
            app.bfgmwavloglin.Value = 'log';
            app.FGMWaveletDropDown = uidropdown(app.MAGPanel);
            app.FGMWaveletDropDown.Items = {'x,r', 'y,t', 'z,n', 'abs', 'sum'};
            app.FGMWaveletDropDown.Value = 'x,r';
            app.WaveletDropDownLabel = uilabel(app.MAGPanel);
            app.WaveletDropDownLabel.Text = 'Wavelet';
            app.B1minCheckBox = uicheckbox(app.MAGPanel);
            app.B1minCheckBox.Text = '1 min';
            app.BETAiCheckBox = uicheckbox(app.PlottingTab);
            app.BETAiCheckBox.Text = 'BETAi';
            app.beta_n_switch = uiswitch(app.PlottingTab, 'slider');
            app.beta_n_switch.Items = {'nscpot', 'nPAS'};
            app.beta_n_switch.Value = 'nscpot';
            app.hrButton = uibutton(app.PlottingTab, 'push');
            app.hrButton.ButtonPushedFcn = createCallbackFcn(app, @hrButtonPushed, true);
            app.hrButton.Text = '+12hr';
            app.hrButton_2 = uibutton(app.PlottingTab, 'push');
            app.hrButton_2.ButtonPushedFcn = createCallbackFcn(app, @hrButton_2Pushed, true);
            app.hrButton_2.Text = '+6hr';
            app.hrButton_3 = uibutton(app.PlottingTab, 'push');
            app.hrButton_3.ButtonPushedFcn = createCallbackFcn(app, @hrButton_3Pushed, true);
            app.hrButton_3.Text = '+2hr';
            app.hrButton_4 = uibutton(app.PlottingTab, 'push');
            app.hrButton_4.ButtonPushedFcn = createCallbackFcn(app, @hrButton_4Pushed, true);
            app.hrButton_4.Text = '+1hr';
            app.standardplotButton = uibutton(app.PlottingTab, 'push');
            app.standardplotButton.ButtonPushedFcn = createCallbackFcn(app, @standardplotButtonPushed, true);
            app.standardplotButton.Text = 'standard plot';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.mvafilterCheckBox = uicheckbox(app.AnalysisTab);
            app.mvafilterCheckBox.Text = 'filter';
            app.PowerspectraButton = uibutton(app.AnalysisTab, 'push');
            app.PowerspectraButton.ButtonPushedFcn = createCallbackFcn(app, @PowerspectraButtonPushed, true);
            app.PowerspectraButton.Text = 'Power spectra';
            app.MVAGUIButton = uibutton(app.AnalysisTab, 'push');
            app.MVAGUIButton.ButtonPushedFcn = createCallbackFcn(app, @MVAGUIButtonPushed, true);
            app.MVAGUIButton.Text = 'MVA GUI';
            app.copyTintButton = uibutton(app.AnalysisTab, 'push');
            app.copyTintButton.ButtonPushedFcn = createCallbackFcn(app, @copyTintButtonPushed, true);
            app.copyTintButton.Text = 'copy Tint';
            app.PSPEC_Switch = uiswitch(app.AnalysisTab, 'slider');
            app.PSPEC_Switch.Items = {'FGM', 'SCM'};
            app.PSPEC_Switch.Value = 'FGM';
            app.pspecfilterCheckBox = uicheckbox(app.AnalysisTab);
            app.pspecfilterCheckBox.Text = 'filter';
            app.AnalysisintervalEditField = uieditfield(app.AnalysisTab, 'text');
            app.AnalysisintervalEditFieldLabel = uilabel(app.AnalysisTab);
            app.AnalysisintervalEditFieldLabel.Text = 'Analysis interval';
            app.mvaminfreq = uieditfield(app.AnalysisTab, 'numeric');
            app.fminhzEditFieldLabel = uilabel(app.AnalysisTab);
            app.fminhzEditFieldLabel.Text = 'fmin (hz)';
            app.mvamaxfreq = uieditfield(app.AnalysisTab, 'numeric');
            app.fmaxhzLabel = uilabel(app.AnalysisTab);
            app.fmaxhzLabel.Text = 'fmax (hz)';
            app.coordDropDownLabel = uilabel(app.AnalysisTab);
            app.coordDropDownLabel.Text = 'coord';
            app.coordDropDown = uidropdown(app.AnalysisTab);
            app.coordDropDown.Items = {'SRF', 'RTN', 'abs', 'fac'};
            app.coordDropDown.Value = 'SRF';
            app.fminHzEditFieldLabel = uilabel(app.AnalysisTab);
            app.fminHzEditFieldLabel.Text = 'fmin (Hz)';
            app.fminHzEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.fmaxHzEditFieldLabel = uilabel(app.AnalysisTab);
            app.fmaxHzEditFieldLabel.Text = 'fmax (Hz)';
            app.fmaxHzEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.BnTEditFieldLabel = uilabel(app.AnalysisTab);
            app.BnTEditFieldLabel.Text = 'B [nT]';
            app.BnTEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.nccEditFieldLabel = uilabel(app.AnalysisTab);
            app.nccEditFieldLabel.Text = 'n [cc]';
            app.nccEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.TieVEditFieldLabel = uilabel(app.AnalysisTab);
            app.TieVEditFieldLabel.Text = 'Ti [eV]';
            app.TieVEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.SelectparameterDropDownLabel = uilabel(app.AnalysisTab);
            app.SelectparameterDropDownLabel.Text = 'Select parameter';
            app.SelectparameterDropDown = uidropdown(app.AnalysisTab);
            app.SelectparameterDropDown.Items = {'Debye_length_[m]', 'Beta_i', 'e-_plasma_f_[kHz]', 'e-_gyrof_[Hz]', 'e-_gyrorad_[km]', 'e-_inert_l_[km]', 'H+_plasma_f_[Hz]', 'H+_gyrof_[Hz]', 'H+_gyrorad_[km]', 'lower_hybr_f_[Hz]', 'Alfven_veloc_[km/s]', 'H+_inert_l_[km]'};
            app.SelectparameterDropDown.Value = 'Debye_length_[m]';
            app.calc_field = uieditfield(app.AnalysisTab, 'numeric');
            app.CalcButton = uibutton(app.AnalysisTab, 'push');
            app.CalcButton.ButtonPushedFcn = createCallbackFcn(app, @CalcButtonPushed, true);
            app.CalcButton.Text = 'Calc';
            app.TeeVEditFieldLabel = uilabel(app.AnalysisTab);
            app.TeeVEditFieldLabel.Text = 'Te [eV]';
            app.TeeVEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.ApproximateplasmaparametersLabel = uilabel(app.AnalysisTab);
            app.ApproximateplasmaparametersLabel.Text = 'Approximate plasma parameters';
            app.LaunchshockGUIButton = uibutton(app.AnalysisTab, 'push');
            app.LaunchshockGUIButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchshockGUIButtonPushed, true);
            app.LaunchshockGUIButton.Text = 'Launch shock GUI';
            app.VDF_toolTab = uitab(app.TabGroup);
            app.VDF_toolTab.Title = 'VDF_tool';
            app.TimeUTCEditFieldLabel = uilabel(app.VDF_toolTab);
            app.TimeUTCEditFieldLabel.Text = 'Time [UTC]';
            app.TimeUTCEditField = uieditfield(app.VDF_toolTab, 'text');
            app.TimeUTCEditField.ValueChangedFcn = createCallbackFcn(app, @TimeUTCEditFieldValueChanged, true);
            app.CoordaintesDropDownLabel = uilabel(app.VDF_toolTab);
            app.CoordaintesDropDownLabel.Text = 'Co-ordaintes';
            app.CoordaintesDropDown = uidropdown(app.VDF_toolTab);
            app.CoordaintesDropDown.Items = {'SRF', 'FAC'};
            app.CoordaintesDropDown.Value = 'SRF';
            app.RemovebackgroundvelocityCheckBox = uicheckbox(app.VDF_toolTab);
            app.RemovebackgroundvelocityCheckBox.Text = 'Remove background velocity';
            app.plot_vdfButton = uibutton(app.VDF_toolTab, 'push');
            app.plot_vdfButton.ButtonPushedFcn = createCallbackFcn(app, @plot_vdfButtonPushed, true);
            app.plot_vdfButton.Text = 'plot_vdf';
            app.EminEditFieldLabel = uilabel(app.VDF_toolTab);
            app.EminEditFieldLabel.Text = 'E min';
            app.EminEditField = uieditfield(app.VDF_toolTab, 'numeric');
            app.EmaxEditFieldLabel = uilabel(app.VDF_toolTab);
            app.EmaxEditFieldLabel.Text = 'E max';
            app.EmaxEditField = uieditfield(app.VDF_toolTab, 'numeric');
            app.StatusEditField_2 = uieditfield(app.VDF_toolTab, 'text');
            app.StatusEditField_2Label = uilabel(app.VDF_toolTab);
            app.StatusEditField_2Label.Text = 'Status';
            app.YYYYMMDDThhmmssssZLabel = uilabel(app.VDF_toolTab);
            app.YYYYMMDDThhmmssssZLabel.Text = 'YYYY-MM-DDThh:mm:ss.ssZ';
            app.VDFdateEditField = uieditfield(app.VDF_toolTab, 'text');
            app.VDFdateEditFieldLabel = uilabel(app.VDF_toolTab);
            app.VDFdateEditFieldLabel.Text = 'VDF date';
            app.nAvEditFieldLabel = uilabel(app.VDF_toolTab);
            app.nAvEditFieldLabel.Text = 'nAv';
            app.nAvEditField = uieditfield(app.VDF_toolTab, 'numeric');
            app.nAvEditField.Value = 1;
            app.VDFintervalEditField = uieditfield(app.VDF_toolTab, 'text');
            app.VDFintervalEditFieldLabel = uilabel(app.VDF_toolTab);
            app.VDFintervalEditFieldLabel.Text = 'VDF interval';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = irf_solo_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end